classdef VaspPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VASPPlotterUIFigure           matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectFolderButton            matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        FileStatusTab                 matlab.ui.container.Tab
        INCARLabel                    matlab.ui.control.Label
        LampIncar                     matlab.ui.control.Lamp
        POSCARLabel                   matlab.ui.control.Label
        LampPoscar                    matlab.ui.control.Lamp
        DOSCARLabel                   matlab.ui.control.Label
        LampDoscar                    matlab.ui.control.Lamp
        EIGVALLabel                   matlab.ui.control.Label
        LampEigenval                  matlab.ui.control.Lamp
        KPOINTSLabel                  matlab.ui.control.Label
        LampKpoints                   matlab.ui.control.Lamp
        SmoothSettingsTab             matlab.ui.container.Tab
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        movmeanButton                 matlab.ui.control.RadioButton
        movmedianButton               matlab.ui.control.RadioButton
        gaussianButton                matlab.ui.control.RadioButton
        lowessButton                  matlab.ui.control.RadioButton
        loessButton                   matlab.ui.control.RadioButton
        rlowessButton                 matlab.ui.control.RadioButton
        rloessButton                  matlab.ui.control.RadioButton
        sgolayButton                  matlab.ui.control.RadioButton
        TabGroup2                     matlab.ui.container.TabGroup
        INCARTab                      matlab.ui.container.Tab
        IncarData                     matlab.ui.control.Table
        POSCARTab                     matlab.ui.container.Tab
        PoscarData                    matlab.ui.control.Table
        KPOINTSTab                    matlab.ui.container.Tab
        KpointsData                   matlab.ui.control.Table
        ResetButton                   matlab.ui.control.Button
        ExportFigureDataButton        matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        bandStructure                 matlab.ui.control.UIAxes
        PlotBandStructureButton       matlab.ui.control.Button
        SaveasButton                  matlab.ui.control.Button
        YmaxSpinnerLabel              matlab.ui.control.Label
        YmaxSpinner                   matlab.ui.control.Spinner
        YminSpinnerLabel              matlab.ui.control.Label
        YminSpinner                   matlab.ui.control.Spinner
        TabGroup3                     matlab.ui.container.TabGroup
        TotalDOSTab                   matlab.ui.container.Tab
        totalDos                      matlab.ui.control.UIAxes
        PlotTotalDosButton            matlab.ui.control.Button
        SaveTotalDosasButton          matlab.ui.control.Button
        YmaxSpinner_2Label            matlab.ui.control.Label
        TotalDosYmaxSpinner           matlab.ui.control.Spinner
        YminSpinner_2Label            matlab.ui.control.Label
        TotalDosYminSpinner           matlab.ui.control.Spinner
        XmaxSpinnerLabel              matlab.ui.control.Label
        TotalDosXmaxSpinner           matlab.ui.control.Spinner
        XminSpinnerLabel              matlab.ui.control.Label
        TotalDosXminSpinner           matlab.ui.control.Spinner
        SmoothTotalDosButton          matlab.ui.control.Button
        ProjectedDOSTab               matlab.ui.container.Tab
        ElementDropDownLabel          matlab.ui.control.Label
        ElementDropDown               matlab.ui.control.DropDown
        projectDos                    matlab.ui.control.UIAxes
        PlotProjectedDosButton        matlab.ui.control.Button
        SaveProjectedDosasButton      matlab.ui.control.Button
        YmaxSpinner_3Label            matlab.ui.control.Label
        ProjectedDosYmaxSpinner       matlab.ui.control.Spinner
        YminSpinner_3Label            matlab.ui.control.Label
        ProjectedDosYminSpinner       matlab.ui.control.Spinner
        XmaxSpinner_2Label            matlab.ui.control.Label
        ProjectedDosXmaxSpinner       matlab.ui.control.Spinner
        XminSpinner_2Label            matlab.ui.control.Label
        ProjectedDosXminSpinner       matlab.ui.control.Spinner
        ProjectedDosSmoothButton      matlab.ui.control.Button
        PlotSegmentsPanel             matlab.ui.container.Panel
        CheckBox_1                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        QuickSelectionButtonGroup     matlab.ui.container.ButtonGroup
        SelectallButton               matlab.ui.control.ToggleButton
        ManualButton                  matlab.ui.control.ToggleButton
        DeselectallButton             matlab.ui.control.ToggleButton
        FatBandTab                    matlab.ui.container.Tab
        AtomNumberDropDownLabel       matlab.ui.control.Label
        AtomNumberDropDown            matlab.ui.control.DropDown
        PlotSegmentsPanel_2           matlab.ui.container.Panel
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_17                   matlab.ui.control.CheckBox
        CheckBox_18                   matlab.ui.control.CheckBox
        QuickSelectionButtonGroup_2   matlab.ui.container.ButtonGroup
        SelectallButton_2             matlab.ui.control.ToggleButton
        ManualButton_2                matlab.ui.control.ToggleButton
        DeselectallButton_2           matlab.ui.control.ToggleButton
        FatBand                       matlab.ui.control.UIAxes
        PlotFatBandButton             matlab.ui.control.Button
        ImportProcarButton            matlab.ui.control.Button
        FatbandscalingParameterEditFieldLabel  matlab.ui.control.Label
        FatbandscalingParameterEditField  matlab.ui.control.NumericEditField
        Wannier90FitTab               matlab.ui.container.Tab
        WannierFitFigure              matlab.ui.control.UIAxes
        ImportWannierFitButton        matlab.ui.control.Button
        PlotWannierFitButton          matlab.ui.control.Button
        ScatterSizeEditFieldLabel     matlab.ui.control.Label
        ScatterSizeEditField          matlab.ui.control.NumericEditField
        DBandStructureTab             matlab.ui.container.Tab
        bandStructure_3D              matlab.ui.control.UIAxes
        ImportBandStructure_3DButton  matlab.ui.control.Button
        Plot3DBandStructureButton     matlab.ui.control.Button
        BandIndexFinderPanel          matlab.ui.container.Panel
        WindowMaxEditFieldLabel       matlab.ui.control.Label
        WindowMaxEditField            matlab.ui.control.NumericEditField
        WindowMinEditFieldLabel       matlab.ui.control.Label
        WindowMinEditField            matlab.ui.control.NumericEditField
        FindBandButton                matlab.ui.control.Button
        BandIndexDropDownLabel        matlab.ui.control.Label
        BandIndexDropDown             matlab.ui.control.DropDown
        KzplaneDropDownLabel          matlab.ui.control.Label
        KzplaneDropDown               matlab.ui.control.DropDown
        ZmaxSpinnerLabel              matlab.ui.control.Label
        ZmaxSpinner                   matlab.ui.control.Spinner
        ZminSpinnerLabel              matlab.ui.control.Label
        ZminSpinner                   matlab.ui.control.Spinner
        MeshDensitySpinnerLabel       matlab.ui.control.Label
        MeshDensitySpinner            matlab.ui.control.Spinner
        HoldOnCheckBox                matlab.ui.control.CheckBox
        FermiSurfaceCheckBox          matlab.ui.control.CheckBox
        OpenHubbardModelFitButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        tmp        
        INCAR
        EIGENVAL
        kpoints
        criticalPoint
        scalingParameter
        order
        pointNumber
        dosPlot
        projectDosPlot
        copyAwaiting
        checkboxProperties
        PROCAR
        fatBandPlot
        colorMap
        wannierFit
        EIGENVAL_3D
        hubbardFittingDialog
    end
    
    properties (Access = public)
        POSCAR
        dirname
        figureData
        DOSCAR
    end
    
    
    methods (Access = private)
        % Function related to initiating
        function initialPanel(app)
            % Hide Smooth Method panel
            app.MethodButtonGroup.Visible = 'off';
            % Initialize other value
            app.tmp.smoothMethod = "movmean";
            app.tmp.selectedAtom = 1;
            app.tmp.fatBandScalingParameter = app.FatbandscalingParameterEditField.Value;
            app.tmp.wannierFitScatterSize = app.ScatterSizeEditField.Value;
            % Create ColorMap for data lines (RGB format)
            app.colorMap = { [127 124 119], [127 127 0], [246 110 184], [152 82 71], ...
                [158 99 181], [239 0 0], [0 161 59], [255 116 0], [0 118 174] };
            app.colorMap = cellfun(@(x) x./255, app.colorMap, 'UniformOutput', false);
            % Use Default CorlorMap First
            app.colorMap = [ app.colorMap(:)', {[0 0.4470 0.7410]}, {[0.8500 0.3250 0.0980]}, {[0.9290 0.6940 0.1250]}, {[0.4940 0.1840 0.5560]}, {[0.4660 0.6740 0.1880]}, ...
                {[0.3010 0.7450 0.9330]}, {[0.6350 0.0780 0.1840]} ];
            % ColorMap Created ended
            
            if app.tmp.lorbit == 0
                app.PlotSegmentsPanel.Visible = 'off';
                app.ElementDropDown.Enable = 'off';
                app.PlotProjectedDosButton.Enable = 'off';
                app.SaveProjectedDosasButton.Enable = 'off';
                app.ProjectedDosXminSpinner.Enable = 'off';
                app.ProjectedDosXmaxSpinner.Enable = 'off';
                app.ProjectedDosYminSpinner.Enable = 'off';
                app.ProjectedDosYmaxSpinner.Enable = 'off';
                app.ProjectedDosSmoothButton.Enable = 'off';
            else
                app.ElementDropDown.Items = app.POSCAR.element.name;
                app.ElementDropDown.Value = app.ElementDropDown.Items(1);
                app.checkboxProperties.name = app.DOSCAR.projectedDOSforEachAtom{1, 1}.Properties.VariableNames(2: end);
                app.checkboxProperties.length = length(app.checkboxProperties.name);
                
                switch app.tmp.spinPolarizedFlag
                    case 0
                        app.CheckBox_1.Text = app.checkboxProperties.name{1};
                        app.CheckBox_2.Text = app.checkboxProperties.name{2};
                        app.CheckBox_3.Text = app.checkboxProperties.name{3};
                        if app.tmp.lorbit == 11
                            app.CheckBox_4.Text = app.checkboxProperties.name{4};
                            app.CheckBox_5.Text = app.checkboxProperties.name{5};
                            app.CheckBox_6.Text = app.checkboxProperties.name{6};
                            app.CheckBox_7.Text = app.checkboxProperties.name{7};
                            app.CheckBox_8.Text = app.checkboxProperties.name{8};
                            app.CheckBox_9.Text = app.checkboxProperties.name{9};
                            app.checkboxProperties.logicalMat = true(1, 9);
                        else
                            app.CheckBox_4.Visible = 'off';
                            app.CheckBox_5.Visible = 'off';
                            app.CheckBox_6.Visible = 'off';
                            app.CheckBox_7.Visible = 'off';
                            app.CheckBox_8.Visible = 'off';
                            app.CheckBox_9.Visible = 'off';
                            app.checkboxProperties.logicalMat = true(1, 3);
                        end
                    case 1
                        if app.tmp.lorbit == 11
                            app.CheckBox_1.Text = 's_DOS';
                            app.CheckBox_2.Text = 'py_DOS';
                            app.CheckBox_3.Text = 'pz_DOS';
                            app.CheckBox_4.Text = 'px_DOS';
                            app.CheckBox_5.Text = 'dxy_DOS';
                            app.CheckBox_6.Text = 'dyz_DOS';
                            app.CheckBox_7.Text = 'dz2_r2__DOS';
                            app.CheckBox_8.Text = 'dxz_DOS';
                            app.CheckBox_9.Text = 'x2_y2_DOS';
                            app.checkboxProperties.logicalMat = true(1, 9);
                        else
                            app.CheckBox_1.Text = 'sDOS';
                            app.CheckBox_2.Text = 'pDOS';
                            app.CheckBox_3.Text = 'dDOS';
                            app.CheckBox_4.Visible = 'off';
                            app.CheckBox_5.Visible = 'off';
                            app.CheckBox_6.Visible = 'off';
                            app.CheckBox_7.Visible = 'off';
                            app.CheckBox_8.Visible = 'off';
                            app.CheckBox_9.Visible = 'off';
                            app.checkboxProperties.logicalMat = true(1, 3);
                        end
                        
                end
                
            end
            
            % Set Fat band DragButton & checkbox text
            switch app.tmp.lorbit
                case 11
                    for numIdx = 1: app.tmp.totAtomNumber
                        app.AtomNumberDropDown.Items{numIdx} = num2str (numIdx);
                    end
                    app.CheckBox_10.Text = 's_DOS';
                    app.CheckBox_11.Text = 'py_DOS';
                    app.CheckBox_12.Text = 'pz_DOS';
                    app.CheckBox_13.Text = 'px_DOS';
                    app.CheckBox_14.Text = 'dxy_DOS';
                    app.CheckBox_15.Text = 'dyz_DOS';
                    app.CheckBox_16.Text = 'dz2_r2__DOS';
                    app.CheckBox_17.Text = 'dxz_DOS';
                    app.CheckBox_18.Text = 'x2_y2_DOS';
                    app.fatBandPlot.checkboxProperties.logicalMat = true(1, 9);
                otherwise
                    app.AtomNumberDropDown.Enable = "off";
                    app.PlotSegmentsPanel_2.Visible = "off";
                    app.QuickSelectionButtonGroup_2.Visible = "off";
                    app.PlotFatBandButton.Enable = "off";
                    app.ImportProcarButton.Enable = "off";
            end
            % Initialize 3D band Struture data
            app.tmp.bandFinderEnergyMax = app.WindowMaxEditField.Value;
            app.tmp.bandFinderEnergyMin = app.WindowMinEditField.Value;
        end
        
        
        % functions related to I/O
        function INCAR = readIncar(app)
            filename = strcat(app.dirname,'/INCAR');
            if ~exist(filename,'file')
                errordlg('INCAR file not found','File Error');
                clear variables;
                return
            end
            
            opts = delimitedTextImportOptions("NumVariables", 2);
            opts.DataLines = [1, Inf];
            opts.Delimiter = "=";
            opts.VariableNames = ["ALGO", "Fast"];
            opts.VariableTypes = ["char", "char"];
            opts = setvaropts(opts, 1, "WhitespaceRule", "preserve");
            opts = setvaropts(opts, 1, "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            INCAR = readtable(filename, opts);
            INCAR = table2cell(INCAR);
            % Delete empty elements & annotations
            numIdx{1} = cellfun('isempty',INCAR(:, 2)) == 1;
            INCAR(numIdx{1}, :) = [];
            numIdx{2} = ~cellfun ( 'isempty', regexp(INCAR(:,1),"^(!)$") );
            INCAR(numIdx{2}, :) = [];
            numIdx{3} = ~cellfun ( 'isempty', regexp(INCAR(:,1),"note") );
            INCAR(numIdx{3}, :) = [];
            for numIdx = 1: length(INCAR)
                INCAR{numIdx,2} = regexprep(INCAR{numIdx,2},'(\s)+!.*','');
            end
            INCAR(:, 3) = INCAR(:, 2);
            numIdx = cellfun(@(x) ~isnan(str2double(x)), INCAR(:, 2));
            % Structure of INCAR cell: INCAR(:, 1) = Control tag names, INCAR(:,
            % 2) = Value(double format), INCAR(:, 3) = Value (char format)
            INCAR(numIdx, 2) = cellfun(@(x) {str2double(x)}, INCAR(numIdx, 2));
        end
        
        function tmp = dataProcessIncar(app)
            % Spin polarized control
            if  ~prod( cellfun('isempty', regexp(app.INCAR(:,1), "(?!\!)^(\s)+MAGMOM") ) ) == 1
                tmp.spinPolarizedFlag = 1;
            elseif  ~prod( cellfun('isempty', regexp(app.INCAR(:,1), "(?!\!)^(\s)+ISPIN") ) ) == 1
                numIdx = ~cellfun ( 'isempty', regexp(app.INCAR(:,1),"ISPIN") );
                if app.INCAR{numIdx, 2} == 2
                    tmp.spinPolarizedFlag = 1;
                else
                    tmp.spinPolarizedFlag = 0;
                end
            else
                tmp.spinPolarizedFlag = 0;
            end
            % Read LORBIT flag (without annotated)
            if ~prod( cellfun('isempty', regexp(app.INCAR(:,1), "(?!\!)^(\s)+LORBIT") ) ) == 1
                numIdx = ~cellfun ( 'isempty', regexp(app.INCAR(:,1),"LORBIT") );
                tmp.lorbit = app.INCAR{numIdx, 2};
            else
                tmp.lorbit = 0;
            end
            % Read NEDOS flag (without annotated)
            if ~prod( cellfun('isempty', regexp(app.INCAR(:,1), "(?!\!)^(\s)+NEDOS") ) ) == 1
                numIdx = ~cellfun ( 'isempty', regexp(app.INCAR(:,1),"NEDOS") );
                tmp.nedos = app.INCAR{numIdx, 2};
            else
                tmp.nedos = 301;
            end
            clear opts numIdx
        end
        
        function [tmp, POSCAR] = readPoscar(app, tmp)
            filename = strcat(app.dirname,'/POSCAR');
            if ~exist(filename,'file')
                errordlg('POSCAR file not found','File Error');
                clear variables;
                return
            end
            
            opts = delimitedTextImportOptions("NumVariables", 4);
            opts.DataLines = [1, Inf];
            opts.Delimiter = ["\t", " "];
            opts.VariableNames = ["col1", "col2", "col3", "col4"];
            opts.VariableTypes = ["string", "string", "string", "string"];
            opts = setvaropts(opts, [1, 2, 3, 4], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, [1, 2, 3, 4], "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            POSCARtemp = readtable(filename, opts);
            POSCARtemp = table2array(POSCARtemp);
            POSCARtemp(ismissing(POSCARtemp)) = "";
            for i = 1:length(POSCARtemp)
                tmp.numIdx = find( strlength(POSCARtemp(i, :)) );
                POSCAR.stringMat(i, 1: length(tmp.numIdx)) = POSCARtemp(i, tmp.numIdx);
            end
            POSCAR.stringMat(ismissing(POSCAR.stringMat)) = "";
            POSCAR.element.name = POSCAR.stringMat(6, :);
            POSCAR.element.name( POSCAR.element.name=='' )= [];
            POSCAR.element.num = str2double (POSCAR.stringMat(7, :) );
            POSCAR.element.num(isnan(POSCAR.element.num)) = [];
            POSCAR.element.totalAtomNumber = sum(POSCAR.element.num);
            tmp.totAtomNumber = sum(POSCAR.element.num);
            clear opts i POSCARtemp
        end
        
        function [tmp, DOSCAR] = readDoscar(app, tmp)
            filename = strcat(app.dirname,'/DOSCAR');
            fileID = fopen(filename,'r');
            if fileID == -1
                errordlg('NOSCAR file not found','File Error');
                clear variables;
                return
            end
            
            switch tmp.spinPolarizedFlag
                case 0
                    opts = delimitedTextImportOptions("NumVariables", 4);
                    try
                        opts.DataLines = [6, 6+tmp.nedos];
                    catch
                        opts.DataLines = [6, 307];
                    end
                    opts.Delimiter = " ";
                    opts.VariableNames = ["energy", "DOS", "intDOS", "fermiLevel"];
                    opts.VariableTypes = ["double", "double", "double", "double"];
                    opts.ExtraColumnsRule = "ignore";
                    opts.EmptyLineRule = "read";
                    opts.ConsecutiveDelimitersRule = "join";
                    opts.LeadingDelimitersRule = "ignore";
                    DOSCAR.dos = readtable(filename, opts);
                    clear opts
                case 1
                    opts = delimitedTextImportOptions("NumVariables", 5);
                    try
                        opts.DataLines = [6, 6+tmp.nedos];
                    catch
                        opts.DataLines = [6, 307];
                    end
                    opts.Delimiter = " ";
                    opts.VariableNames = ["energy", "DOS_Up", "DOS_Down", "intDOS_Up", "intDOS_Down"];
                    opts.VariableTypes = ["double", "double", "double", "double", "double"];
                    opts.ExtraColumnsRule = "ignore";
                    opts.EmptyLineRule = "read";
                    opts.ConsecutiveDelimitersRule = "join";
                    opts.LeadingDelimitersRule = "ignore";
                    DOSCAR.dos = readtable(filename, opts);
                    clear opts
            end
            DOSCAR.fermiLevel = DOSCAR.dos(1, 4);
            DOSCAR.fermiLevel = table2array(DOSCAR.fermiLevel);
            tmp.scanNedosFlag = DOSCAR.dos(1, 3);
            tmp.scanNedosFlag = table2array(tmp.scanNedosFlag);
            DOSCAR.dos(1, :) = [];
            
            switch tmp.lorbit
                case 10
                    switch tmp.spinPolarizedFlag
                        case 0
                            opts = delimitedTextImportOptions("NumVariables", 4);
                            opts.DataLines = [tmp.nedos+7, Inf];
                            opts.Delimiter = " ";
                            opts.VariableNames = ["energy", "sDOS", "pDOS", "dDOS"];
                            opts.VariableTypes = ["double", "double", "double", "double"];
                            opts.ExtraColumnsRule = "ignore";
                            opts.EmptyLineRule = "read";
                            opts.ConsecutiveDelimitersRule = "join";
                            opts.LeadingDelimitersRule = "ignore";
                            DOSCAR.projectedDOStemp = readtable(filename, opts);
                            clear opts
                        case 1
                            opts = delimitedTextImportOptions("NumVariables", 7);
                            opts.DataLines = [tmp.nedos+7, Inf];
                            opts.Delimiter = " ";
                            opts.VariableNames = ["energy", "sDOS_Up", "sDOS_Down", "pDOS_Up", "pDOS_Down", "dDOS_Up", "dDOS_Down"];
                            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double"];
                            opts = setvaropts(opts, [6, 7], "TrimNonNumeric", true);
                            opts = setvaropts(opts, [6, 7], "ThousandsSeparator", ",");
                            opts.ExtraColumnsRule = "ignore";
                            opts.EmptyLineRule = "read";
                            opts.ConsecutiveDelimitersRule = "join";
                            opts.LeadingDelimitersRule = "ignore";
                            DOSCAR.projectedDOStemp = readtable(filename, opts);
                            clear opts
                    end
                case 11
                    switch tmp.spinPolarizedFlag
                        case 0
                            opts = delimitedTextImportOptions("NumVariables", 10);
                            opts.DataLines = [tmp.nedos+7, Inf];
                            opts.Delimiter = " ";
                            opts.VariableNames = ["energy", "s_DOS", "py_DOS", "pz_DOS", "px_DOS", "dxy_DOS", "dyz_DOS", "dz2_r2__DOS", "dxz_DOS", "dx2_y2_DOS"];
                            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
                            opts = setvaropts(opts, [4, 5, 6, 7, 8, 9, 10], "TrimNonNumeric", true);
                            opts = setvaropts(opts, [4, 5, 6, 7, 8, 9, 10], "ThousandsSeparator", ",");
                            opts.ExtraColumnsRule = "ignore";
                            opts.EmptyLineRule = "read";
                            opts.ConsecutiveDelimitersRule = "join";
                            opts.LeadingDelimitersRule = "ignore";
                            DOSCAR.projectedDOStemp = readtable(filename, opts);
                            clear opts
                        case 1
                            opts = delimitedTextImportOptions("NumVariables", 19);
                            opts.DataLines = [tmp.nedos+7, Inf];
                            opts.Delimiter = " ";
                            opts.VariableNames = ["energy", "s_DOS_Up", "s_DOS_Down", "py_DOS_Up", "py_DOS_Down", "pz_DOS_Up", "pz_DOS_Down", "px_DOS_Up", "px_DOS_Down", "dxy_DOS_Up", "dxy_DOS_Down", "dyz_DOS_Up", "dyz_DOS_Down", "dz2_r2__DOS_Up", "dz2_r2__DOS_Down", "dxz_DOS_Up", "dxz_DOS_Down", "x2_y2_DOS_Up", "x2_y2_DOS_Down"];
                            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
                            opts = setvaropts(opts, [6, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16, 17, 18, 19], "TrimNonNumeric", true);
                            opts = setvaropts(opts, [6, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16, 17, 18, 19], "ThousandsSeparator", ",");
                            opts.ExtraColumnsRule = "ignore";
                            opts.EmptyLineRule = "read";
                            opts.ConsecutiveDelimitersRule = "join";
                            opts.LeadingDelimitersRule = "ignore";
                            DOSCAR.projectedDOStemp = readtable(filename, opts);
                            clear opts
                    end               
            end
            
            if tmp.lorbit ~= 0
                for index = 1: height(DOSCAR.projectedDOStemp) / (tmp.nedos + 1)
                tmp.lowerbound =  2 + (tmp.nedos + 1) * (index - 1);
                tmp.upperbound =  (tmp.nedos + 1)*index;
                DOSCAR.projectedDOSforEachAtom{index} = DOSCAR.projectedDOStemp(tmp.lowerbound:tmp.upperbound, :);
                end
            end
            
            % Check NEDOS flag in DOSCAR & INCAR            
            tmp.nedoChangeFlag = 0;
%             switch tmp.lorbit
%                 case 0
%                     tmp.scanNedosFlag = length(DOSCAR.dos.energy);
%                 otherwise
%                     numIdx = find(DOSCAR.projectedDOStemp{:,4} == DOSCAR.fermiLevel == 1);
%                     tmp.scanNedosFlag = numIdx(2) - numIdx(1) - 1;
%             end
            
            if tmp.scanNedosFlag ~= tmp.nedos
                tmp.str = sprintf("Detected NEDOS flag is %d rather than %d, use NEDOS = %d instead?", tmp.scanNedosFlag, tmp.nedos, tmp.scanNedosFlag);
                tmp.questionButton = questdlg(tmp.str, "NEDOS checking", 'Yes', 'No', 'No');
            end
            
            try
                switch tmp.questionButton
                    case "Yes"
                        tmp.nedos = tmp.scanNedosFlag;
                        tmp.nedoChangeFlag = 1;
                    case "No"
                        tmp.nedos = tmp.nedos;
                end
            catch
                tmp.nedos = tmp.nedos;
            end
            clear index numIdx
        end
        
        function [tmp, EIGENVAL] = readEigenvalue(app, tmp)
            filename = strcat(app.dirname,'/EIGENVAL');
            if ~exist(filename,'file')
                errordlg('EIGENVAL file not found','File Error');
                clear variables;
                return
            end
            
            switch tmp.spinPolarizedFlag
                case 0
                    opts = delimitedTextImportOptions("NumVariables", 4);
                    opts.DataLines = [6, Inf];
                    opts.Delimiter = " ";
                    opts.VariableNames = ["order", "energy", "eigenvalue", "Var4"];
                    opts.SelectedVariableNames = ["order", "energy", "eigenvalue"];
                    opts.VariableTypes = ["double", "double", "double", "string"];
                    opts = setvaropts(opts, [1, 2], "TrimNonNumeric", true);
                    opts = setvaropts(opts, [1, 2], "ThousandsSeparator", ",");
                    opts.ExtraColumnsRule = "ignore";
                    opts.EmptyLineRule = "read";
                    opts.ConsecutiveDelimitersRule = "join";
                    opts.LeadingDelimitersRule = "ignore";
                case 1
                    opts = delimitedTextImportOptions("NumVariables", 25);
                    opts.DataLines = [6, Inf];
                    opts.Delimiter = " ";
                    opts.VariableNames = ["order", "energyUp", "energyDown", "eigenvalueUp", "eigenvalueDown", "Var6", "Var7", "Var8", "Var9", "Var10", "Var11", "Var12", "Var13", "Var14", "Var15", "Var16", "Var17", "Var18", "Var19", "Var20", "Var21", "Var22", "Var23", "Var24", "Var25"];
                    opts.SelectedVariableNames = ["order", "energyUp", "energyDown", "eigenvalueUp", "eigenvalueDown"];
                    opts.VariableTypes = ["double", "double", "double", "double", "double", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char", "char"];
                    opts = setvaropts(opts, [6, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16, 17, 18, 19, 20, 21, 22, 23, 24, 25], "WhitespaceRule", "preserve");
                    opts = setvaropts(opts, [1, 2], "TrimNonNumeric", true);
                    opts = setvaropts(opts, [1, 2], "ThousandsSeparator", ",");
                    opts = setvaropts(opts, [6, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16, 17, 18, 19, 20, 21, 22, 23, 24, 25], "EmptyFieldRule", "auto");
                    opts.ExtraColumnsRule = "ignore";
                    opts.EmptyLineRule = "read";
                    opts.ConsecutiveDelimitersRule = "join";
                    opts.LeadingDelimitersRule = "ignore";
            end
            EIGENVAL = readtable(filename, opts);
            clear opts
        end
        
        function [tmp, kpoints] = readKpoints(app, tmp)
            filename = strcat(app.dirname,'/KPOINTS');
            if ~exist(filename,'file')
                f = errordlg('KPOINTS file not found','File Error');
                set(f,'fontsize',40);
                clear variables;
                return
            end
            
            opts = delimitedTextImportOptions("NumVariables", 6);
            opts.DataLines = [5, inf];
            opts.Delimiter = ["\t", " ", "!"];
            opts.VariableNames = ["VarName1", "VarName2", "VarName3", "VarName4", "VarName5", "VarName6"];
            opts.VariableTypes = ["string", "string", "string", "string", "string", "string"];
            opts = setvaropts(opts, [1, 2, 3, 4, 5, 6], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, [1, 2, 3, 4, 5, 6], "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            
            KPOINTS = readtable(filename, opts);
            KPOINTS = table2cell(KPOINTS);
            numIdx = cellfun(@(x) ~isnan(str2double(x)), KPOINTS);
            KPOINTS(numIdx) = cellfun(@(x) {str2double(x)}, KPOINTS(numIdx));
            
            numIdx = cellfun(@ismissing, KPOINTS);
            KPOINTS(numIdx) = [];
            numIdx = cellfun(@(x) isequal((x),""), KPOINTS);
            KPOINTS(numIdx) = [];
            KPOINTS = reshape(KPOINTS,[length(KPOINTS)/4 4]);
            KPOINTS = cell2table(KPOINTS,'VariableNames', ["x", "y", "z", "symmetrydot"]);
            kpoints = KPOINTS;
            clear opts KPOINTS numIdx
            
            opts = delimitedTextImportOptions("NumVariables",1);
            opts.DataLines = [2, 2];
            opts.Delimiter = " ";
            opts.VariableNames = "pointNumber";
            opts.VariableTypes = "double";
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            
            kpointsExtra = readtable(filename, opts);
            kpoints.pointNumber = zeros(length(kpoints.x),1);
            kpoints.pointNumber(1) = kpointsExtra.pointNumber;
            clear opts dirname filename kpointsExtra
            fclose('all');
        end
        %functions related to band structure plot
        function tmp = getNproperties(app, tmp, EIGENVAL)
            switch app.tmp.spinPolarizedFlag
                case 0
                    tmp.netot = EIGENVAL.order(1);
                    tmp.nktot = EIGENVAL.energy(1);
                    tmp.nbands = EIGENVAL.eigenvalue(1);  
                case 1
                    tmp.netot = EIGENVAL.order(1);
                    tmp.nktot = EIGENVAL.energyUp(1);
                    tmp.nbands = EIGENVAL.energyDown(1);  
            end
        end
        
        function [criticalPoint, scalingParameter] = calc_criticalPoint(app, pointNumber, nktot, stringMat, kPoint)
            criticalPoint = zeros(1,nktot/pointNumber);
            scalingParameter = zeros(1,nktot/pointNumber);
            % Calculate lattice vector in reciprocal space
            SCAL = str2double(app.POSCAR.stringMat(2,1));
            a1 = str2double(stringMat(3, 1: 3))*SCAL;
            a2 = str2double(stringMat(4, 1: 3))*SCAL;
            a3 = str2double(stringMat(5, 1: 3))*SCAL;
            V = dot(a1, cross(a2, a3));
            
            b1 = 2*pi*cross(a2,a3)/V;
            b2 = 2*pi*cross(a3,a1)/V;
            b3 = 2*pi*cross(a1,a2)/V;
            transMat = [b1;b2;b3];
            
            kPointRecp = zeros(length(kPoint), 3);
            for i = 1: length(kPoint)
                kPointRecp(i, :) = kPoint(i, :) * transMat;
                if i > 1
                    try
                        criticalPoint(i - 1) = norm(kPointRecp(i, :) - kPointRecp(i-1, :)) + criticalPoint(i - 2);
                        scalingParameter(i - 1) = (criticalPoint(i - 1) - criticalPoint(i - 2)) / (pointNumber - 1);
                    catch
                        criticalPoint(i - 1) = norm(kPointRecp(i, :) - kPointRecp(i - 1, :));
                        scalingParameter(i - 1) = criticalPoint(i - 1) / (pointNumber - 1);
                    end
                end
            end
            
        end
            
        function order = calc_order(app, pointNumber, j, scalingParameter, criticalPoint)
            levelIndex = floor(j/pointNumber);
            if floor(levelIndex) == 0
                order = (j - 1) * app.tmp.scalingParameter(1);
            else
                if mod(j,pointNumber) == 0
                    order = criticalPoint(levelIndex);
                else
                    order = criticalPoint(levelIndex) + (j - 1 - pointNumber * levelIndex) * scalingParameter(levelIndex + 1);
                end
            end
            
        end
            
        function [bandplot, tmp] = plotBandLineWithoutSpin(app, tmp,EIGENVAL,kpoints,fermiLevel)
            for i = 1: app.tmp.nbands
                for j = 1: tmp.nktot
                    bandplot.order(j) = calc_order(app, kpoints.pointNumber(1),j, tmp.scalingParameter, tmp.criticalPoint);
                    bandplot.energy{i}(j) = EIGENVAL.energy( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) ) - fermiLevel;
                    bandplot.eigenvalue{i}(j) = EIGENVAL.eigenvalue( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) );
                    if mod(j, kpoints.pointNumber(1)) == 0 && j ~=  tmp.nktot
                        bandplot.energy{i}(j) = NaN;
                        bandplot.eigenvalue{i}(j) = NaN;
                    end
                end
                tmp.removePosition = find(isnan(bandplot.energy{i}));
                tmp.removeLength = length(tmp.removePosition);
                bandplot.energy{i}(tmp.removePosition) = [];
                bandplot.eigenvalue{i}(tmp.removePosition) = [];
                bandplot.order(tmp.removePosition) = [];
                plot(app.bandStructure, bandplot.order(1: end),bandplot.energy{i},"Color","b");
            end
            
            end
            
        function [bandplot, tmp] = plotBandLineWithSpin(app, tmp,EIGENVAL,kpoints,fermiLevel)
            for i = 1: tmp.nbands
                for j = 1: tmp.nktot
                    bandplot.order(j) = calc_order(app, kpoints.pointNumber(1), j, tmp.scalingParameter, tmp.criticalPoint);
                    bandplot.energyUp{i}(j) = EIGENVAL.energyUp( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) ) - fermiLevel;
                    bandplot.energyDown{i}(j) = EIGENVAL.energyDown( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) ) - fermiLevel;
                    bandplot.eigenvalueUp{i}(j) = EIGENVAL.eigenvalueUp( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) );
                    bandplot.eigenvalueDown{i}(j) = EIGENVAL.eigenvalueDown( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) );
                    if mod(j, kpoints.pointNumber(1)) == 0 && j ~=  tmp.nktot
                        bandplot.energyUp{i}(j) = NaN;
                        bandplot.energyDown{i}(j) = NaN;
                        bandplot.eigenvalueUp{i}(j) = NaN;
                        bandplot.eigenvalueDown{i}(j) = NaN;
                    end
                end
                tmp.removePosition = find(isnan(bandplot.energyUp{i}));
                tmp.removeLength = length(tmp.removePosition);
                bandplot.energyUp{i}(tmp.removePosition) = [];
                bandplot.energyDown{i}(tmp.removePosition) = [];
                bandplot.eigenvalueUp{i}(tmp.removePosition) = [];
                bandplot.eigenvalueDown{i}(tmp.removePosition) = [];
                bandplot.order(tmp.removePosition) = [];
                plot(app.bandStructure, bandplot.order(1: end),bandplot.energyUp{i},'b');
                plot(app.bandStructure, bandplot.order(1: end),bandplot.energyDown{i},'r--');
            end
            legend(app.bandStructure, {'SpinUp','SpinDown'},'AutoUpdate','off','Location','southeast');
        end
        
        function myCopyObj(app, axTo)
            axFrom = app.copyAwaiting;
            % Custom version of copyobj() which works to copy a UIAxes object to
            % an old-school axes object.
            
            % Copy children (lines).
            copyobj(axFrom.Children, axTo);
            % Copy titles and labels.
            copyobj([axFrom.Title axFrom.XLabel axFrom.YLabel], axTo)
            
            % Transfer other properties.
            uiAxParams = get(axFrom);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axTo));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; ...
            'Position'; 'OuterPosition'; 'XLabel'; 'YLabel'; 'ZLabel'; 'Title'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(axTo, uiAxGoodParams);
        end
        
    % Functions Related to DOS Plot
        function dosPlot = plotDos(app, DOSCAR, spinPolarizedFlag, xLim)
            switch spinPolarizedFlag
                case 0
                    dosPlot.energy = DOSCAR.dos.energy - DOSCAR.fermiLevel;
                    dosPlot.dos = DOSCAR.dos.DOS;
                    dosPlot.figure = app.totalDos;
                    plot(app.totalDos, dosPlot.energy, dosPlot.dos);
                    app.totalDos.XAxis.Limits = xLim;
                    legend(app.totalDos, 'Total DOS', "AutoUpdate","off");
                case 1
                    dosPlot.energy = DOSCAR.dos.energy - DOSCAR.fermiLevel;
                    dosPlot.dos_up = DOSCAR.dos.DOS_Up;
                    dosPlot.dos_down = - DOSCAR.dos.DOS_Down;
                    dosPlot.figure = app.totalDos;
                    plot(app.totalDos, dosPlot.energy, dosPlot.dos_up, dosPlot.energy, dosPlot.dos_down);
                    app.totalDos.XAxis.Limits = xLim;
                    legend(app.totalDos, ["SpinUp", "SpinDown"], "AutoUpdate","off");
            end
            hold (app.totalDos,"on");
            plot(app.totalDos, [0 0], app.totalDos.YAxis.Limits, "--black");
            hold (app.totalDos,"off");
        end
    
        function projectDosPlot = plotProDos(app, DOSCAR, tmp, element, xLim, colorMap)
            % If projectDosElement Flag are not consistent with POSCAR elements,
            % default plots the 1st atom.
            if isempty(element.name(strcmp(element.name, tmp.projectDosElement)))
                projectDosElementOrder = 1;
            else
                tmpMat = 1:15;
                projectDosElementOrder = tmpMat(strcmp(element.name, tmp.projectDosElement));
            end


            if projectDosElementOrder == 1
                projectDosAtomOrder = 1;
            else
                projectDosAtomOrder = sum(element.num(1: projectDosElementOrder - 1)) + 1;
            end
            
            columnName = DOSCAR.projectedDOSforEachAtom{1, projectDosAtomOrder}.Properties.VariableNames;
            projectDosPlot.figure = app.projectDos;
            hold (app.projectDos, "on")
            projectDosPlot.energy = DOSCAR.projectedDOSforEachAtom{1, projectDosAtomOrder}.energy - DOSCAR.fermiLevel;
            switch tmp.spinPolarizedFlag
                case 0
                    for i = 1: length(columnName) - 1
                        projectDosPlot.line{i} = plot(app.projectDos, projectDosPlot.energy, DOSCAR.projectedDOSforEachAtom{1, projectDosAtomOrder}{:, i + 1}, ...
                            "Color",colorMap{i});
                    end
                case 1
                    for i = 1: length(columnName) - 1
                        switch mod(i, 2) == 1
                            case 1
                                projectDosPlot.line{i} = plot(app.projectDos, projectDosPlot.energy, DOSCAR.projectedDOSforEachAtom{1, projectDosAtomOrder}{:, i + 1}, ...
                                    'Color', colorMap{ceil(i/2)}, 'LineWidth', 1.1);
                            case 0
                                projectDosPlot.line{i} = plot(app.projectDos, projectDosPlot.energy, - DOSCAR.projectedDOSforEachAtom{1, projectDosAtomOrder}{:, i + 1}, ...
                                    'Color', colorMap{ceil(i/2)}, 'LineWidth', 1.1);
                        end
                    end
            end
            app.projectDos.XLim = xLim;
            legend(app.projectDos, columnName(2: end),'Interpreter','none','AutoUpdate','off');
            plot(app.projectDos, [0 0], app.projectDos.YLim, "--black");
            hold (app.projectDos, "off");
        end
        
        function updateCheckBoxStatus(app, processTypeId)
            %Type Id:
            %   1: ProjectedDOS process
            %   2: Fat band plot process
            switch processTypeId
                case 1
                    for i = 1: length(app.checkboxProperties.logicalMat)
                        if app.checkboxProperties.logicalMat(i)
                            app.checkboxProperties.statusMat(i) = "on";
                        else
                            app.checkboxProperties.statusMat(i) = "off";
                        end
                        
                        switch app.tmp.spinPolarizedFlag
                            case 0
                                app.projectDos.Children(length(app.projectDos.Children) + 1 - i).Visible = app.checkboxProperties.statusMat(i);
                            case 1
                                app.projectDos.Children(length(app.projectDos.Children) + 2 - 2*i).Visible = app.checkboxProperties.statusMat(i);
                                app.projectDos.Children(length(app.projectDos.Children) + 1 - 2*i).Visible = app.checkboxProperties.statusMat(i);
                        end
                    end
                case 2
                    for i = 1: length(app.checkboxProperties.logicalMat)
                        if app.fatBandPlot.checkboxProperties.logicalMat(i)
                            app.fatBandPlot.checkboxProperties.statusMat(i) = "on";
                        else
                            app.fatBandPlot.checkboxProperties.statusMat(i) = "off";
                        end
                        for j = 1: app.tmp.nbands
                            app.fatBandPlot.bandplot.scatter{j, i}.Visible = app.fatBandPlot.checkboxProperties.statusMat(i);
                        end
                    end
            end
        end
        
        function Procar = readProcar(app)
            filename = strcat(app.dirname,'/PROCAR');
            if ~exist(filename,'file')
                [name, path] = uigetfile('*.*');
                filename = strcat(path,name);
                if ~exist(filename,'file')
                    errordlg('PROCAR file not found','File Error');
                    clear variables;
                    return
                end
            end   
            opts = delimitedTextImportOptions("NumVariables", 12);
            opts.DataLines = [2, Inf];
            opts.Delimiter = " ";
            opts.VariableNames = ["iron", "s", "py", "pz", "px", "dxy", "dyz", "dz2", "dxz", "x2_y2", "tot", "ironNumber"];
            opts.SelectedVariableNames = ["iron", "s", "py", "pz", "px", "dxy", "dyz", "dz2", "dxz", "x2_y2", "tot", "ironNumber"];
            opts.VariableTypes = ["categorical", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
            opts = setvaropts(opts, [2, 3, 4, 6, 8, 12], "TrimNonNumeric", true);
            opts = setvaropts(opts, [2, 3, 4, 6, 8, 12], "ThousandsSeparator", ",");
            opts = setvaropts(opts, 1, "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            opts.LeadingDelimitersRule = "ignore";
            Procar.origin = readtable(filename, opts);
            Procar.totkpoints = Procar.origin{1,4};
            Procar.totbands = Procar.origin{1,8};
            Procar.totirons = Procar.origin{1,12};
            Procar.origin(isnan(Procar.origin.s), :) = [];
            clear opts          
        end
        
        function dataProcessProcar(app)
            procarWaitBar = uiprogressdlg(app.VASPPlotterUIFigure, "Title", "Importing PROCAR","Message", "Formatting PROCAR");
            for kpoint = 1:app.PROCAR.totkpoints
                for nbands = 1: app.PROCAR.totbands
                    readBaseLine = ( (kpoint - 1 ) * ((app.PROCAR.totirons + 2) * (app.PROCAR.totbands) + 1) + 2) + (app.PROCAR.totirons + 2) * (nbands - 1);
                    app.PROCAR.processed{nbands, kpoint}.bandIndex = nbands;
                    app.PROCAR.processed{nbands, kpoint}.energy = app.PROCAR.origin{readBaseLine, 5};
                    app.PROCAR.processed{nbands, kpoint}.occ = app.PROCAR.origin{readBaseLine, 8};
                    app.PROCAR.processed{nbands, kpoint}.weight = app.PROCAR.origin (readBaseLine + 1: readBaseLine + app.PROCAR.totirons + 1, 1: 11);
                end
                procarWaitBar.Value = kpoint / app.PROCAR.totkpoints;
            end
        end
        
        function [bandplot, tmp, processBar] = plotFatBandLineWithoutSpin(app, tmp, processBar, status)
            if status == "new"
                bandplot = app.figureData.bandStructure;
            else
                bandplot = app.fatBandPlot.bandplot;
            end
            
            for i = 1: tmp.nbands
                if status == "new"
                    % get weight from PROCAR file for fat band ploting
                    s = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 2, app.PROCAR);
                    py = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 3, app.PROCAR);
                    pz = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 4, app.PROCAR);
                    px = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 5, app.PROCAR);
                    dxy = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 6, app.PROCAR);
                    dyz = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 7, app.PROCAR);
                    dz2 = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 8, app.PROCAR);
                    dxz = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 9, app.PROCAR);
                    dx2_y2 = getFatBandWeight (app, tmp.selectedAtom, i, tmp.nktot, 10, app.PROCAR);
                    bandplot.originalWeight{i} = table (s, py, pz, px, dxy, dyz, dz2, dxz, dx2_y2);
                    bandplot.originalWeight{i}(tmp.removePosition, :) = [];                    
                end
                bandplot.processedWeight{i} = bandplot.originalWeight{i}{:, :} * tmp.fatBandScalingParameter;
                plot(app.FatBand, bandplot.order(1: end),bandplot.energy{i},"Color","b");
                processBar.Value = i / tmp.nbands;
            end
            clear s py pz px dxy dyz dz2 dxz dx2_y2;
        end
        
        function weight = getFatBandWeight (~, atomNumber, nbands, totkpoints, orbitIndex, PROCAR)
            % orbitIndex:
            %     2:  s
            %     3:  py
            %     4:  pz
            %     5:  px
            %     6:  dxy
            %     7:  dyz
            %     8:  dz^2
            %     9:  dxz
            %     10: dx2_y2
            %     11: tot
            weight = zeros (totkpoints, 1);
            for numIdx = 1: totkpoints
                weight(numIdx) = PROCAR.processed{nbands, numIdx}.weight{atomNumber, orbitIndex};
            end
        end
        
        function [bandplot, tmp, processBar] = plotFatBandWeightWithoutSpin(app, tmp, bandplot, processBar, colorMap)
            for i = 1: app.tmp.nbands
                for orbitIndex = 1: 9
                    bandplot.scatter{i, orbitIndex} = scatter (app.FatBand,bandplot.order, bandplot.energy{i}, ... 
                        bandplot.processedWeight{i}(:, orbitIndex) + 0.0001, colorMap{orbitIndex}, "filled");
                    bandplot.scatter{i, orbitIndex}.MarkerEdgeAlpha = 0.5;
                    bandplot.scatter{i, orbitIndex}.MarkerFaceAlpha = 0.5;
                end
                processBar.Value = i / app.tmp.nbands;
            end
        end
        
        function [bandplot, tmp] = plotBandStructureDotsWithoutSpin(app, tmp, EIGENVAL, kpoints, fermiLevel, wannierFit, scatterSize)
            for i = 1: app.tmp.nbands
                for j = 1: tmp.nktot
                    bandplot.order(j) = calc_order(app, kpoints.pointNumber(1),j, tmp.scalingParameter, tmp.criticalPoint);
                    bandplot.energy{i}(j) = EIGENVAL.energy( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) ) - fermiLevel;
                    bandplot.eigenvalue{i}(j) = EIGENVAL.eigenvalue( (tmp.nbands + 2) * j + i - (tmp.nbands - 1) );
                    if mod(j, kpoints.pointNumber(1)) == 0 && j ~=  tmp.nktot
                        bandplot.energy{i}(j) = NaN;
                        bandplot.eigenvalue{i}(j) = NaN;
                    end
                end
                tmp.removePosition = find(isnan(bandplot.energy{i}));
                tmp.removeLength = length(tmp.removePosition);
                bandplot.energy{i}(tmp.removePosition) = [];
                bandplot.eigenvalue{i}(tmp.removePosition) = [];
                bandplot.order(tmp.removePosition) = [];
                bandplot.scatter{i} = scatter(wannierFit, bandplot.order(1: end),bandplot.energy{i}, scatterSize, "blue");
            end
        end
        
        function [bandplot, tmp] = plotWannierFitWithoutSpin(app, tmp, fermiLevel, wannierFit, bandplot)
            for wannierband = 1: app.wannierFit.bandNumber
                wannierIndexRange = app.wannierFit.totkpoints * (wannierband - 1) + 1: app.wannierFit.totkpoints * wannierband;
                bandplot.wannierFitLines{wannierband} = plot (wannierFit, app.wannierFit.originData(wannierIndexRange, 1),...
                    app.wannierFit.originData(wannierIndexRange, 2) - fermiLevel, "Color",'r',"LineWidth",1.0);
            end
        end
        
        
        function readEigenvalue_3D(app, filename)
            opts = delimitedTextImportOptions("NumVariables", 25);
            opts.DataLines = [8, Inf];
            opts.Delimiter = " ";
            opts.VariableNames = ["VarName1", "VarName2", "VarName3", "VarName4", "VarName5"];
            opts.SelectedVariableNames = ["VarName1", "VarName2", "VarName3", "VarName4", "VarName5"];
            opts.VariableTypes = ["double", "double", "double", "double", "double"];
            opts = setvaropts(opts, [1, 2], "TrimNonNumeric", true);
            opts = setvaropts(opts, [1, 2], "ThousandsSeparator", ",");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";
            opts.LeadingDelimitersRule = "ignore";
            app.EIGENVAL_3D.originalData = readtable(filename, opts);
            clear opts
        end
        
        function index = dataProcessEigenval_3D(app)
            % Delete the whitespace line, get the number of bands & kpoints
            % independently.
            app.EIGENVAL_3D.processedData = table2cell(app.EIGENVAL_3D.originalData);
            numIdx = cellfun(@(x) ~isnan(str2double(x)), app.EIGENVAL_3D.processedData);
            app.EIGENVAL_3D.processedData(numIdx) = cellfun(@(x) {str2double(x)}, app.EIGENVAL_3D.processedData(numIdx));
            numIdx = cellfun(@(x) isnan((x)), app.EIGENVAL_3D.processedData(:, 1));
            app.EIGENVAL_3D.processedData(numIdx, :) = [];
            numIdx = cellfun(@(x) mod((x),1), app.EIGENVAL_3D.processedData(:, 1));
            i = 2;
            while ~numIdx(i)
                i = i + 1;
            end
            app.EIGENVAL_3D.totBands = i - 2;
            app.EIGENVAL_3D.totKpoints = length(app.EIGENVAL_3D.processedData)/(app.EIGENVAL_3D.totBands + 1);
            % Initialize the data structure, read data formally
            readBaseLine = 1;
            app.EIGENVAL_3D.kpointsWeight = zeros (app.EIGENVAL_3D.totKpoints, 1);
            app.EIGENVAL_3D.kpointsMesh = zeros (3, app.EIGENVAL_3D.totKpoints);
            app.EIGENVAL_3D.bandEigenvalue = cell(app.EIGENVAL_3D.totBands, app.EIGENVAL_3D.totKpoints);
            app.EIGENVAL_3D.bandOccupation = cell(app.EIGENVAL_3D.totBands, app.EIGENVAL_3D.totKpoints);
            
            for orderKpoint = 1: app.EIGENVAL_3D.totKpoints
                app.EIGENVAL_3D.kpointsWeight(orderKpoint) = app.EIGENVAL_3D.processedData{readBaseLine, 4};
                app.EIGENVAL_3D.kpointsMesh(1, orderKpoint) = app.EIGENVAL_3D.processedData{readBaseLine, 1};
                app.EIGENVAL_3D.kpointsMesh(2, orderKpoint) = app.EIGENVAL_3D.processedData{readBaseLine, 2};
                app.EIGENVAL_3D.kpointsMesh(3, orderKpoint) = app.EIGENVAL_3D.processedData{readBaseLine, 3};
                readBaseLine = readBaseLine + 1;
                for orderBands = 1: app.EIGENVAL_3D.totBands
                    app.EIGENVAL_3D.bandEigenvalue{orderBands, orderKpoint} = app.EIGENVAL_3D.processedData{readBaseLine + orderBands - 1, 2};
                    app.EIGENVAL_3D.bandOccupation{orderBands, orderKpoint} = app.EIGENVAL_3D.processedData{readBaseLine + orderBands - 1, 3};
                end
                readBaseLine = readBaseLine + app.EIGENVAL_3D.totBands;
            end
            app.EIGENVAL_3D.kpointsMesh(4, :) = 1: length(app.EIGENVAL_3D.kpointsMesh);
            % Extend the k-mesh to k_x in [-1, 1], k_y in [-1, 1].
            % Mark the order of the EigenValue in the kpoints mat
            
            % k_y < 0, mirror along x-axies            
            numIdx = app.EIGENVAL_3D.kpointsMesh(2, :) < 0;
            app.EIGENVAL_3D.kpointsMesh = [app.EIGENVAL_3D.kpointsMesh(:, numIdx) + [0 1 0 0]', app.EIGENVAL_3D.kpointsMesh];
            % k_y > 0, mirror along x-axies
            numIdx = app.EIGENVAL_3D.kpointsMesh(2, :) > 0;
            app.EIGENVAL_3D.kpointsMesh = [app.EIGENVAL_3D.kpointsMesh(:, numIdx) + [0 -1 0 0]', app.EIGENVAL_3D.kpointsMesh];
            % K_X < 0, mirror along y-axies
            numIdx = app.EIGENVAL_3D.kpointsMesh(1, :) < 0;
            app.EIGENVAL_3D.kpointsMesh = [app.EIGENVAL_3D.kpointsMesh(:, numIdx) + [1 0 0 0]', app.EIGENVAL_3D.kpointsMesh];
            % k_x > 0, mirror along y-axis
            numIdx = app.EIGENVAL_3D.kpointsMesh(1, :) > 0;
            app.EIGENVAL_3D.kpointsMesh = [app.EIGENVAL_3D.kpointsMesh(:, numIdx) + [-1 0 0 0]', app.EIGENVAL_3D.kpointsMesh];
            % Sort the expanded result, index will be used in bandplot
            app.EIGENVAL_3D.kpointsMesh = sortrows(app.EIGENVAL_3D.kpointsMesh');
            app.EIGENVAL_3D.kpointsMesh = app.EIGENVAL_3D.kpointsMesh';
            % K-mesh process (direct based to reciprocal based)
            SCAL = str2double(app.POSCAR.stringMat(2,1));
            a1 = str2double(app.POSCAR.stringMat(3, 1: 3))*SCAL;
            a2 = str2double(app.POSCAR.stringMat(4, 1: 3))*SCAL;
            a3 = str2double(app.POSCAR.stringMat(5, 1: 3))*SCAL;
            V = dot(a1, cross(a2, a3));
            
            b1 = 2*pi*cross(a2,a3)/V;
            b2 = 2*pi*cross(a3,a1)/V;
            b3 = 2*pi*cross(a1,a2)/V;
            app.EIGENVAL_3D.transMat = [b1;b2;b3];            
            app.EIGENVAL_3D.kpointsRecipMesh = zeros(4, length(app.EIGENVAL_3D.kpointsMesh));
            for i = 1: length(app.EIGENVAL_3D.kpointsRecipMesh)
                app.EIGENVAL_3D.kpointsRecipMesh(:, i) = [app.EIGENVAL_3D.kpointsMesh(1: 3, i)' * app.EIGENVAL_3D.transMat, app.EIGENVAL_3D.kpointsMesh(4, i)];
            end                      
            % Delete the duplicated elements
            numIdx = false([1, length(app.EIGENVAL_3D.kpointsRecipMesh)]);
            for i = 1: length(app.EIGENVAL_3D.kpointsRecipMesh) - 1
                if app.EIGENVAL_3D.kpointsRecipMesh(2, i) == app.EIGENVAL_3D.kpointsRecipMesh(2, i+1)
                    numIdx(i) = 1;
                end
            end
            app.EIGENVAL_3D.kpointsRecipMesh(:, numIdx) = [];
            % Set the restriction of the 1st Brillouin zone (Hex lattice only!!)
            a = norm(a1);
            xCriteria1 = abs(app.EIGENVAL_3D.kpointsRecipMesh(1, :)) < 2*pi/(3*a) ;
            yCriteria1 = abs(app.EIGENVAL_3D.kpointsRecipMesh(2, :)) < 2*pi/(sqrt(3)*a);
            xCriteria2 = ((app.EIGENVAL_3D.kpointsRecipMesh(1, :)) < -2*pi/(3*a)&((app.EIGENVAL_3D.kpointsRecipMesh(1, :)) >  -4*pi/(3*a)));
            yCriteria2 = abs(app.EIGENVAL_3D.kpointsRecipMesh(2, :)) < sqrt(3)*app.EIGENVAL_3D.kpointsRecipMesh(1, :) + 4*pi/(sqrt(3)*a);
            xCriteria3 = ((app.EIGENVAL_3D.kpointsRecipMesh(1, :)) > 2*pi/(3*a)&((app.EIGENVAL_3D.kpointsRecipMesh(1, :)) <  4*pi/(3*a)));
            yCriteria3 = abs(app.EIGENVAL_3D.kpointsRecipMesh(2, :)) < -sqrt(3)*app.EIGENVAL_3D.kpointsRecipMesh(1, :) + 4*pi/(sqrt(3)*a);
            numIdx = xCriteria1.*yCriteria1 + xCriteria2.*yCriteria2 + xCriteria3.*yCriteria3;
            index = logical(numIdx);
            % Set the trival z-value to zero for usage of selection panel,
            % Process Fermi level.
            numIdx = abs(app.EIGENVAL_3D.kpointsMesh(3, :)) < 1e-15;
            app.EIGENVAL_3D.kpointsMesh(3, numIdx) = 0;
            app.EIGENVAL_3D.kpointZvalue = unique(app.EIGENVAL_3D.kpointsMesh(3, :));
            app.EIGENVAL_3D.bandEigenvalue = cellfun(@(x) ((x) - app.DOSCAR.fermiLevel), app.EIGENVAL_3D.bandEigenvalue);      
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
%            app.dirname = 'D:\tmp\test';
            try
                app.dirname = uigetdir('C:\');
            catch
                app.dirname = uigetdir('/');
            end
            if app.dirname == 0
                warndlg('Selection cenceled.','Warning');
                clear variables;
                return;
            end
            
            app.INCAR = readIncar(app);
            app.tmp = dataProcessIncar(app);
            app.LampIncar.Color = "green";
            app.IncarData.Data = app.INCAR(:, 1:2);
            [app.tmp, app.POSCAR] = readPoscar(app, app.tmp);
            app.LampPoscar.Color = "green";
            app.PoscarData.Data = array2table(app.POSCAR.stringMat);
            [app.tmp, app.DOSCAR] = readDoscar(app, app.tmp);
            if app.tmp.nedoChangeFlag == 1
                clear DOSCAR;
                [app.tmp, app.DOSCAR] = readDoscar(app, app.tmp);
            end
            app.LampDoscar.Color = "green";
            [app.tmp, app.EIGENVAL] = readEigenvalue(app, app.tmp);
            app.LampEigenval.Color = "green";
            [app.tmp, app.kpoints] = readKpoints(app, app.tmp);
            app.LampKpoints.Color = "green";
            app.KpointsData.Data = app.kpoints(:, 1: 4);
            % Panel initiating according to LORBIT Flag
            initialPanel(app);
        end

        % Display data changed function: IncarData
        function IncarDataDisplayDataChanged(app, event)
            newDisplayData = app.IncarData.DisplayData;
            app.INCAR = newDisplayData;
            app.INCAR(:, 3) = app.INCAR(:, 2);
            numIdx = cellfun(@(x) ~isnan(str2double(x)), app.INCAR(:, 2));
            app.INCAR(numIdx, 2) = cellfun(@(x) {str2double(x)}, app.INCAR(numIdx, 2));
            app.LampDoscar.Color = "white";
            [app.tmp, app.DOSCAR] = readDoscar(app, app.tmp);
            app.LampDoscar.Color = "green";
            app.LampEigenval = "white";
            [app.tmp, app.EIGENVAL] = readEigenvalue(app, app.tmp);
            app.LampEigenval.Color = "green";
        end

        % Button pushed function: PlotBandStructureButton
        function PlotBandStructureButtonPushed(app, event)
            app.tmp.kDistance.length = length(app.kpoints.x)./2;
            app.tmp.plotUpperBound = 4;
            app.tmp.plotLowerBound = -4;
            % Generate x-axis scaling parameter
            for i = 1: app.tmp.kDistance.length
                app.tmp.highSymmetryDotArray(i) = app.kpoints.symmetrydot(2*i - 1);
                app.tmp.kPoint(i, 1) = app.kpoints.x(2*i-1);
                app.tmp.kPoint(i, 2) = app.kpoints.y(2*i-1);
                app.tmp.kPoint(i, 3) = app.kpoints.z(2*i-1);
                if i == app.tmp.kDistance.length
                    app.tmp.kPoint(i + 1, 1) = app.kpoints.x(end);
                    app.tmp.kPoint(i + 1, 2) = app.kpoints.y(end);
                    app.tmp.kPoint(i + 1, 3) = app.kpoints.z(end);
                    app.tmp.highSymmetryDotArray(app.tmp.kDistance.length + 1) = app.kpoints.symmetrydot(end);
                end
            end
            app.tmp = getNproperties(app, app.tmp,app.EIGENVAL);
            app.tmp.removeLength = length(app.kpoints.symmetrydot)/2 - 1;
            [app.tmp.criticalPoint,app.tmp.scalingParameter] = calc_criticalPoint(app, app.kpoints.pointNumber(1),app.tmp.nktot, app.POSCAR.stringMat, app.tmp.kPoint);
            hold(app.bandStructure,"on");
            switch app.tmp.spinPolarizedFlag
                case 0
                    [bandplot, app.tmp] = plotBandLineWithoutSpin(app, app.tmp,app.EIGENVAL,app.kpoints,app.DOSCAR.fermiLevel);
                case 1
                    [bandplot, app.tmp] = plotBandLineWithSpin(app, app.tmp,app.EIGENVAL,app.kpoints,app.DOSCAR.fermiLevel);
            end
            
            % Generate high symmetric point line
            for i = 1: length(app.tmp.scalingParameter)
                bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                plot(app.bandStructure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
            end
            plot(app.bandStructure, bandplot.order, linspace(0, 0, length(bandplot.order)), '--k');
            
            hold(app.bandStructure,"off");
            xticks(app.bandStructure, [0, app.tmp.criticalPoint]);
            xticklabels(app.bandStructure, app.tmp.highSymmetryDotArray);
            axis(app.bandStructure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
            title(app.bandStructure, "Band Structure");
            app.YmaxSpinner.Value = app.tmp.plotUpperBound;
            app.YminSpinner.Value = app.tmp.plotLowerBound;
            app.figureData.bandStructure = bandplot;
            clear i j 
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            app.copyAwaiting = app.bandStructure;
            % Define figure and axes to specifications.
            f = figure('Visible', false);
            axNew = axes(f);
            % Copy.
            myCopyObj(app, axNew);
            % Print!
            ax = gca;
            legend(ax, app.copyAwaiting.Legend.String, 'AutoUpdate','off','Location','southeast');
            [filename, pathname] = uiputfile({'*.png'; '*.fig'; '*.jpg'});
            if ~ischar(filename); return; end   %user cancel
            fullname = fullfile(pathname, filename);
            saveas(ax, fullname);
            fig = ancestor(ax, 'figure');
            close(fig);
            delete(f);
        end

        % Value changing function: YmaxSpinner
        function YmaxSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if ischar(changingValue) == 1
                changingValue = str2double(changingValue);
            end
            app.tmp.plotUpperBound = changingValue;
            axis(app.bandStructure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
        end

        % Value changed function: YmaxSpinner
        function YmaxSpinnerValueChanged(app, event)
            value = app.YmaxSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.tmp.plotUpperBound = value;
            axis(app.bandStructure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
            hold(app.bandStructure,"on");
            for i = 1: length(app.tmp.scalingParameter)
                bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                plot(app.bandStructure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
            end
            hold(app.bandStructure,"off");
            if ~isempty(app.FatBand.Children)
                axis(app.FatBand, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
                hold(app.FatBand,"on");
                for i = 1: length(app.tmp.scalingParameter)
                    bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                    bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                    plot(app.FatBand, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
                end
                hold(app.FatBand,"off");
            end
            if ~isempty(app.WannierFitFigure.Children)
                axis(app.WannierFitFigure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
                hold(app.WannierFitFigure,"on");
                for i = 1: length(app.tmp.scalingParameter)
                    bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                    bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                    plot(app.WannierFitFigure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
                end
                hold(app.WannierFitFigure,"off");
            end
        end

        % Value changing function: YminSpinner
        function YminSpinnerValueChanging(app, event)
            changingValue = event.Value;
            if ischar(changingValue) == 1
                changingValue = str2double(changingValue);
            end
            app.tmp.plotLowerBound = changingValue;
            axis(app.bandStructure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
        end

        % Value changed function: YminSpinner
        function YminSpinnerValueChanged(app, event)
            value = app.YminSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.tmp.plotLowerBound = value;
            axis(app.bandStructure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
            hold(app.bandStructure,"on");
            for i = 1: length(app.tmp.scalingParameter)
                bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                plot(app.bandStructure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
            end
            hold(app.bandStructure,"off");
            if ~isempty(app.FatBand.Children)
                axis(app.FatBand, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
                hold(app.FatBand,"on");
                for i = 1: length(app.tmp.scalingParameter)
                    bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                    bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                    plot(app.FatBand, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
                end
                hold(app.FatBand,"off");
            end
            if ~isempty(app.WannierFitFigure.Children)
                axis(app.WannierFitFigure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
                hold(app.WannierFitFigure,"on");
                for i = 1: length(app.tmp.scalingParameter)
                    bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                    bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                    plot(app.WannierFitFigure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
                end
                hold(app.WannierFitFigure,"off");
            end
        end

        % Callback function
        function ResetButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            app.checkboxProperties.logicalMat(1) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Button pushed function: PlotTotalDosButton
        function PlotTotalDosButtonPushed(app, event)
            cla(app.totalDos, 'reset');
            app.tmp.xLim = [-8, 8];
            app.dosPlot.figure = plotDos(app, app.DOSCAR, app.tmp.spinPolarizedFlag, app.tmp.xLim);
            app.dosPlot.axis = [app.tmp.xLim, app.totalDos.YLim];
            app.TotalDosXminSpinner.Value = app.dosPlot.axis(1);
            app.TotalDosXmaxSpinner.Value = app.dosPlot.axis(2);
            app.TotalDosYminSpinner.Value = app.dosPlot.axis(3);
            app.TotalDosYmaxSpinner.Value = app.dosPlot.axis(4);
            app.figureData.totalDOS = app.dosPlot;
        end

        % Value changed function: TotalDosXmaxSpinner
        function TotalDosXmaxSpinnerValueChanged(app, event)
            value = app.TotalDosXmaxSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.dosPlot.axis(2) = value;
            axis(app.totalDos, app.dosPlot.axis);
        end

        % Value changed function: TotalDosXminSpinner
        function TotalDosXminSpinnerValueChanged(app, event)
            value = app.TotalDosXminSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.dosPlot.axis(1) = value;
            axis(app.totalDos, app.dosPlot.axis);
        end

        % Value changed function: TotalDosYminSpinner
        function TotalDosYminSpinnerValueChanged(app, event)
            value = app.TotalDosYminSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.dosPlot.axis(3) = value;
            axis(app.totalDos, app.dosPlot.axis);
            hold (app.totalDos,"on");
            plot(app.totalDos, [0 0], app.dosPlot.axis(3: 4), "--black");
            hold (app.totalDos,"off");
        end

        % Value changed function: TotalDosYmaxSpinner
        function TotalDosYmaxSpinnerValueChanged(app, event)
            value = app.TotalDosYmaxSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.dosPlot.axis(4) = value;
            axis(app.totalDos, app.dosPlot.axis);
            hold (app.totalDos,"on");
            plot(app.totalDos, [0 0], app.dosPlot.axis(3: 4), "--black");
            hold (app.totalDos,"off");
        end

        % Button pushed function: SmoothTotalDosButton
        function SmoothTotalDosButtonPushed(app, event)
            app.DOSCAR.dos = [app.DOSCAR.dos(:, 1), smoothdata(app.DOSCAR.dos(:,2: end), app.tmp.smoothMethod)];
            PlotTotalDosButtonPushed(app, event);
        end

        % Button pushed function: SaveTotalDosasButton
        function SaveTotaltDosasButtonPushed(app, event)
            app.copyAwaiting = app.totalDos;
            % Define figure and axes to specifications.
            f = figure('Visible', false);
            axNew = axes(f);
            % Copy.
            myCopyObj(app, axNew);
            % Print!
            ax = gca;
            legend(ax, app.copyAwaiting.Legend.String);
            [filename, pathname] = uiputfile({'*.png'; '*.fig'; '*.jpg'});
            if ~ischar(filename); return; end   %user cancel
            fullname = fullfile(pathname, filename);
            saveas(ax, fullname);
            fig = ancestor(ax, 'figure');
            close(fig);
            delete(f);
        end

        % Button pushed function: ProjectedDosSmoothButton
        function ProjectedDosSmoothButtonPushed(app, event)
            app.tmp.orderMat = 1:15;
            if isempty(app.POSCAR.element.name(strcmp(app.POSCAR.element.name, app.tmp.projectDosElement)))
                app.tmp.projectDosElementOrder = 1;
            else
                app.tmp.projectDosElementOrder = app.tmp.orderMat(strcmp(app.POSCAR.element.name, app.tmp.projectDosElement));
            end
            
            if app.tmp.projectDosElementOrder == 1
                app.tmp.projectDosAtomOrder = 1;
            else
                app.tmp.projectDosAtomOrder = sum(app.POSCAR.element.num(1: app.tmp.projectDosElementOrder - 1)) + 1;
            end
            
            app.DOSCAR.projectedDOSforEachAtom{1, app.tmp.projectDosAtomOrder} = [ app.DOSCAR.projectedDOSforEachAtom{1, app.tmp.projectDosAtomOrder}(:, 1), ...
                smoothdata(app.DOSCAR.projectedDOSforEachAtom{1, app.tmp.projectDosAtomOrder}(:,2: end), app.tmp.smoothMethod)];
            PlotProjectedDosButtonPushed(app, event);
        end

        % Button pushed function: PlotProjectedDosButton
        function PlotProjectedDosButtonPushed(app, event)
            cla(app.projectDos, 'reset');
            app.tmp.xLim = [-8, 8];
            app.tmp.projectDosElement = app.ElementDropDown.Value;
            app.projectDosPlot = plotProDos(app, app.DOSCAR, app.tmp, app.POSCAR.element, app.tmp.xLim, app.colorMap);
            app.projectDosPlot.axis = [app.tmp.xLim, app.projectDos.YLim];
            app.ProjectedDosXminSpinner.Value = app.projectDosPlot.axis(1);
            app.ProjectedDosXmaxSpinner.Value = app.projectDosPlot.axis(2);
            app.ProjectedDosYminSpinner.Value = app.projectDosPlot.axis(3);
            app.ProjectedDosYmaxSpinner.Value = app.projectDosPlot.axis(4);
            updateCheckBoxStatus(app, 1);
            app.figureData.projectDOS = app.projectDosPlot;
        end

        % Value changed function: ElementDropDown
        function ElementDropDownValueChanged(app, event)
            value = app.ElementDropDown.Value;
            app.tmp.projectDosElement = value;
        end

        % Value changed function: ProjectedDosXminSpinner
        function ProjectedDosXminSpinnerValueChanged(app, event)
            value = app.ProjectedDosXminSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.projectDosPlot.axis(1) = value;
            axis(app.projectDos, app.projectDosPlot.axis);
        end

        % Value changed function: ProjectedDosXmaxSpinner
        function ProjectedDosXmaxSpinnerValueChanged(app, event)
            value = app.ProjectedDosXmaxSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.projectDosPlot.axis(2) = value;
            axis(app.projectDos, app.projectDosPlot.axis);
        end

        % Value changed function: ProjectedDosYminSpinner
        function ProjectedDosYminSpinnerValueChanged(app, event)
            value = app.ProjectedDosYminSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.projectDosPlot.axis(3) = value;
            axis(app.projectDos, app.projectDosPlot.axis);
            hold (app.projectDos,"on");
            plot(app.projectDos, [0 0], app.projectDosPlot.axis(3: 4), "--black");
            hold (app.projectDos,"off");
        end

        % Value changed function: ProjectedDosYmaxSpinner
        function ProjectedDosYmaxSpinnerValueChanged(app, event)
            value = app.ProjectedDosYmaxSpinner.Value;
            if ischar(value) == 1
                value = str2double(value);
            end
            app.projectDosPlot.axis(4) = value;
            axis(app.projectDos, app.projectDosPlot.axis);
            hold (app.projectDos,"on");
            plot(app.projectDos, [0 0], app.projectDosPlot.axis(3: 4), "--black");
            hold (app.projectDos,"off");
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            app.checkboxProperties.logicalMat(2) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            app.checkboxProperties.logicalMat(3) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            app.checkboxProperties.logicalMat(4) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            app.checkboxProperties.logicalMat(5) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            app.checkboxProperties.logicalMat(6) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            app.checkboxProperties.logicalMat(7) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            app.checkboxProperties.logicalMat(8) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            app.checkboxProperties.logicalMat(9) = value;
            if ~isempty(app.projectDos.Children)
                updateCheckBoxStatus(app, 1);
            end
            if app.QuickSelectionButtonGroup.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup.Buttons(2).Value = 1;                
            end
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            app.tmp.smoothMethod = selectedButton.Text;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
            app.VASPPlotterUIFigure.Visible = 'off';
            % Open 2nd instance of app
            VaspPlotter();  % <--------------The name of your app
            % Delete old instance
            close(app.VASPPlotterUIFigure)
        end

        % Button pushed function: ExportFigureDataButton
        function ExportFigureDataButton4Pushed(app, event)
            % Data Fomatting
            app.figureData.projectDOS.projectedDOSforEachAtom = app.DOSCAR.projectedDOSforEachAtom;
            app.figureData.fermiLevel = app.DOSCAR.fermiLevel;
            if isfield(app.figureData, "totalDOS") && isfield(app.figureData.totalDOS.figure, "figure")
            app.figureData.totalDOS.dos = app.figureData.totalDOS.figure.dos;
            app.figureData.totalDOS.energy = app.figureData.totalDOS.figure.energy;
            app.figureData.totalDOS.figure = app.figureData.totalDOS.figure.figure;
            end
            exportData = app.figureData;
            save ("savedFigureData.mat","exportData","-mat");
            app.figureData = exportData;    % Eliminate the warning message
        end

        % Selection changed function: QuickSelectionButtonGroup
        function QuickSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.QuickSelectionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Select all'
                    app.CheckBox_1.Value = 1;
                    app.CheckBox_2.Value = 1;
                    app.CheckBox_3.Value = 1;
                    app.CheckBox_4.Value = 1;
                    app.CheckBox_5.Value = 1;
                    app.CheckBox_6.Value = 1;
                    app.CheckBox_7.Value = 1;
                    app.CheckBox_8.Value = 1;
                    app.CheckBox_9.Value = 1;
                    app.checkboxProperties.logicalMat = ones(1,9);
                    updateCheckBoxStatus(app, 1);
                case 'Deselect all'
                    app.CheckBox_1.Value = 0;
                    app.CheckBox_2.Value = 0;
                    app.CheckBox_3.Value = 0;
                    app.CheckBox_4.Value = 0;
                    app.CheckBox_5.Value = 0;
                    app.CheckBox_6.Value = 0;
                    app.CheckBox_7.Value = 0;
                    app.CheckBox_8.Value = 0;
                    app.CheckBox_9.Value = 0;
                    app.checkboxProperties.logicalMat = zeros(1,9);
                    updateCheckBoxStatus(app, 1);
            end
        end

        % Button pushed function: SaveProjectedDosasButton
        function SaveProjectDosasButtonPushed(app, event)
            app.copyAwaiting = app.projectDos;
            % Define figure and axes to specifications.
            f = figure('Visible', false);
            axNew = axes(f);
            % Copy.
            myCopyObj(app, axNew);
            % Print!
            ax = gca;
            legend(ax, app.copyAwaiting.Legend.String);
            [filename, pathname] = uiputfile({'*.png'; '*.fig'; '*.jpg'});
            if ~ischar(filename); return; end   %user cancel
            fullname = fullfile(pathname, filename);
            saveas(ax, fullname);
            fig = ancestor(ax, 'figure');
            close(fig);
            delete(f);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.VASPPlotterUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {803, 803};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {304, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ImportProcarButton
        function ImportProcarButtonPushed(app, event)
            app.PROCAR = readProcar(app);
            dataProcessProcar(app);
        end

        % Button pushed function: PlotFatBandButton
        function PlotFatBandButtonPushed(app, event)
            if isempty(app.bandStructure.Children)
                app.PlotBandStructureButtonPushed(app);
            end
            
            if isempty(app.FatBand.Children)
                status = "new";
                
            else
                cla(app.FatBand);
                status = "old";
            end
            hold(app.FatBand,"on");
            processBar = uiprogressdlg (app.VASPPlotterUIFigure, "Title", "Fat Band Plot", "Message", "Processing Band Structure Data","Value",0);
            [bandplot, app.tmp, processBar] = plotFatBandLineWithoutSpin(app, app.tmp, processBar, status);
            processBar.Message = "Processing Fat Band Data";
            processBar.Value = 0;
            [bandplot, app.tmp, processBar] = plotFatBandWeightWithoutSpin(app, app.tmp, bandplot, processBar, app.colorMap);
            app.fatBandPlot.bandplot = bandplot;
            processBar.Message = "Applying axis settings";
            processBar.Indeterminate = "on";
            % Generate high symmetric point line
            for i = 1: length(app.tmp.scalingParameter)
                bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                plot(app.FatBand, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
            end
            plot(app.FatBand, bandplot.order, linspace(0, 0, length(bandplot.order)), '--k');        
            hold(app.FatBand,"off");
            xticks(app.FatBand, [0, app.tmp.criticalPoint]);
            xticklabels(app.FatBand, app.tmp.highSymmetryDotArray);
            axis(app.FatBand, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
            title(app.FatBand, "Fat Band");
            legend([app.fatBandPlot.bandplot.scatter{1, :}], ["s" "p_y" "p_z" "p_x" "d_{xy}" "d_{yz}" "d_{z^2-r^2}" "d_{xz}" "d_{x^2-y^2}"], 'AutoUpdate','off');
            app.YmaxSpinner.Value = app.tmp.plotUpperBound;
            app.YminSpinner.Value = app.tmp.plotLowerBound;
            app.figureData.fatBand = bandplot;
            clear i j 
            close (processBar);
        end

        % Selection changed function: QuickSelectionButtonGroup_2
        function QuickSelectionButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.QuickSelectionButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'Select all'
                    app.CheckBox_10.Value = 1;
                    app.CheckBox_11.Value = 1;
                    app.CheckBox_12.Value = 1;
                    app.CheckBox_13.Value = 1;
                    app.CheckBox_14.Value = 1;
                    app.CheckBox_15.Value = 1;
                    app.CheckBox_16.Value = 1;
                    app.CheckBox_17.Value = 1;
                    app.CheckBox_18.Value = 1;
                    app.fatBandPlot.checkboxProperties.logicalMat = ones(1,9);
                    updateCheckBoxStatus(app, 2);
                case 'Deselect all'
                    app.CheckBox_10.Value = 0;
                    app.CheckBox_11.Value = 0;
                    app.CheckBox_12.Value = 0;
                    app.CheckBox_13.Value = 0;
                    app.CheckBox_14.Value = 0;
                    app.CheckBox_15.Value = 0;
                    app.CheckBox_16.Value = 0;
                    app.CheckBox_17.Value = 0;
                    app.CheckBox_18.Value = 0;
                    app.fatBandPlot.checkboxProperties.logicalMat = zeros(1,9);
                    updateCheckBoxStatus(app, 2);
            end
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(1) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,1}.Visible = app.fatBandPlot.checkboxProperties.statusMat(1);
            end
        end

        % Value changed function: AtomNumberDropDown
        function AtomNumberDropDownValueChanged(app, event)
            value = app.AtomNumberDropDown.Value;
            app.tmp.selectedAtom = str2double(value);
            cla(app.FatBand);
        end

        % Value changed function: FatbandscalingParameterEditField
        function FatbandscalingParameterEditFieldValueChanged(app, event)
            value = app.FatbandscalingParameterEditField.Value;
            app.tmp.fatBandScalingParameter = value;
            PlotFatBandButtonPushed(app);
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            value = app.CheckBox_11.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(2) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,2}.Visible = app.fatBandPlot.checkboxProperties.statusMat(2);
            end
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            value = app.CheckBox_12.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(3) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,3}.Visible = app.fatBandPlot.checkboxProperties.statusMat(3);
            end
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            value = app.CheckBox_13.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(4) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,4}.Visible = app.fatBandPlot.checkboxProperties.statusMat(4);
            end
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            value = app.CheckBox_14.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(5) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,5}.Visible = app.fatBandPlot.checkboxProperties.statusMat(5);
            end
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            value = app.CheckBox_15.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(6) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,6}.Visible = app.fatBandPlot.checkboxProperties.statusMat(6);
            end
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
            value = app.CheckBox_16.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(7) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,7}.Visible = app.fatBandPlot.checkboxProperties.statusMat(7);
            end
        end

        % Value changed function: CheckBox_17
        function CheckBox_17ValueChanged(app, event)
            value = app.CheckBox_17.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(8) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,8}.Visible = app.fatBandPlot.checkboxProperties.statusMat(8);
            end
        end

        % Value changed function: CheckBox_18
        function CheckBox_18ValueChanged(app, event)
            value = app.CheckBox_18.Value;
            app.fatBandPlot.checkboxProperties.logicalMat(9) = value;
            if ~isempty(app.FatBand.Children)
                updateCheckBoxStatus(app, 2);
            end
            if app.QuickSelectionButtonGroup_2.Buttons(2).Value ~= 1
                app.QuickSelectionButtonGroup_2.Buttons(2).Value = 1;                
            end
            for i = 1: app.tmp.nbands
                app.fatBandPlot.bandplot.scatter{i,9}.Visible = app.fatBandPlot.checkboxProperties.statusMat(9);
            end
        end

        % Button pushed function: ImportWannierFitButton
        function ImportWannierFitButtonPushed(app, event)
            filename = strcat(app.dirname,'/wannier90_band.dat');
            if ~exist(filename,'file')
                [name, path] = uigetfile('*.dat');
                filename = strcat(path,name);
                if ~exist(filename,'file')
                    errordlg('Wannier band file not found','File Error');
                    clear variables;
                    return
                end
            end
            app.wannierFit.originData = load (filename);
            app.wannierFit.totkpoints = find (app.wannierFit.originData == max(app.wannierFit.originData(:, 1)), 1);
            app.wannierFit.bandNumber = length(app.wannierFit.originData) / app.wannierFit.totkpoints;
        end

        % Button pushed function: PlotWannierFitButton
        function PlotWannierFitButtonPushed(app, event)
            if isempty(app.bandStructure.Children)
                app.PlotBandStructureButtonPushed(app);
            end
            hold(app.WannierFitFigure,"on");
            processBar = uiprogressdlg (app.VASPPlotterUIFigure, "Title", "Wannier Fit", "Message", "Processing Band Structure Data","Indeterminate","on");
            [bandplot, app.tmp] = plotBandStructureDotsWithoutSpin(app, app.tmp,app.EIGENVAL,app.kpoints,app.DOSCAR.fermiLevel, app.WannierFitFigure, app.tmp.wannierFitScatterSize);
            [bandplot, app.tmp] = plotWannierFitWithoutSpin(app, app.tmp, app.DOSCAR.fermiLevel, app.WannierFitFigure, bandplot);
            app.wannierFit.bandplot = bandplot;
            % Generate high symmetric point line
            for i = 1: length(app.tmp.scalingParameter)
                bandplot.xSymmetryGrid = [app.tmp.criticalPoint(i), app.tmp.criticalPoint(i)];
                bandplot.ySymmetryGrid = [app.tmp.plotLowerBound, app.tmp.plotUpperBound];
                plot(app.WannierFitFigure, bandplot.xSymmetryGrid, bandplot.ySymmetryGrid,'k');
            end
            plot(app.WannierFitFigure, bandplot.order, linspace(0, 0, length(bandplot.order)), '--k');        
            hold(app.WannierFitFigure,"off");
            xticks(app.WannierFitFigure, [0, app.tmp.criticalPoint]);
            xticklabels(app.WannierFitFigure, app.tmp.highSymmetryDotArray);
            axis(app.WannierFitFigure, [0,app.tmp.criticalPoint(end),app.tmp.plotLowerBound,app.tmp.plotUpperBound]);
            title(app.WannierFitFigure, "Wannier Fit");
%            legend([app.fatBandPlot.bandplot.scatter{1, :}], ["s" "p_y" "p_z" "p_x" "d_{xy}" "d_{yz}" "d_{z^2-r^2}" "d_{xz}" "d_{x^2-y^2}"], 'AutoUpdate','off');
            app.YmaxSpinner.Value = app.tmp.plotUpperBound;
            app.YminSpinner.Value = app.tmp.plotLowerBound;
            close (processBar);
            app.figureData.wannierFit = bandplot;
            clear i j
        end

        % Value changed function: ScatterSizeEditField
        function ScatterSizeEditFieldValueChanged(app, event)
            value = app.ScatterSizeEditField.Value;
            app.tmp.wannierFitScatterSize = value;
            cla(app.WannierFitFigure);
            PlotWannierFitButtonPushed(app);
        end

        % Button pushed function: ImportBandStructure_3DButton
        function ImportBandStructure_3DButtonPushed(app, event)
            filename = strcat(app.dirname,'/EIGENVAL_3D');
            if ~exist(filename,'file')
                [name, path] = uigetfile('*.*');
                filename = strcat(path,name);
                if ~exist(filename,'file')
                    errordlg('Static calculation full-BZ zone EIGENVAL file not found','File Error');
                    clear variables;
                    return
                end
            end
            % Create Processbar
            processBar = uiprogressdlg (app.VASPPlotterUIFigure, "Title", "3D Eigenvalue Import", "Message", "Importing Eigenvalue Data","Value",0);
            processBar.Indeterminate = "on";
            
            readEigenvalue_3D(app, filename);
            app.EIGENVAL_3D.brillouinZoneIndex = dataProcessEigenval_3D(app);
            app.WindowMaxEditField.Enable = "on";
            app.WindowMinEditField.Enable = "on";
            app.FindBandButton.Enable = "on";
            app.HoldOnCheckBox.Enable = "on";
            app.KzplaneDropDown.Items = {'Null'};
            for numIdx = 1: length(app.EIGENVAL_3D.kpointZvalue)
                app.KzplaneDropDown.Items{numIdx} = num2str(app.EIGENVAL_3D.kpointZvalue(numIdx));
            end
            app.KzplaneDropDown.Value = app.KzplaneDropDown.Items(1);         
            close(processBar);
        end

        % Button pushed function: FindBandButton
        function FindBandButtonPushed(app, event)
            % Initialize the Band Index & Kz-plane button
            app.KzplaneDropDown.Enable = "on";
            app.BandIndexDropDown.Enable = "on";
            upperBound = app.tmp.bandFinderEnergyMax;
            lowerBound = app.tmp.bandFinderEnergyMin;
            bandIndex = 1: app.EIGENVAL_3D.totBands;
            judgeFlag = zeros(1, app.EIGENVAL_3D.totBands);
            for numIdx = 1: app.EIGENVAL_3D.totBands
                judgeNumIdx = app.EIGENVAL_3D.bandEigenvalue(numIdx, :) < upperBound ...
                    & app.EIGENVAL_3D.bandEigenvalue(numIdx, :) > lowerBound;
                judgeFlag(numIdx) = prod (judgeNumIdx);
            end
            bandIndexSelection = bandIndex(logical(judgeFlag));
            app.BandIndexDropDown.Items = {'Null'};
            for numIdx = 1: length(bandIndexSelection)
                app.BandIndexDropDown.Items{numIdx} = num2str(bandIndexSelection(numIdx));
            end
            app.BandIndexDropDown.Value = app.BandIndexDropDown.Items(1);
            
            % Transport the button selection to the variables
            app.EIGENVAL_3D.bandIndexSelection = str2double (app.BandIndexDropDown.Value);
            app.EIGENVAL_3D.kzPlaneSelection = str2double (app.KzplaneDropDown.Value);
            app.tmp.meshDensity = app.MeshDensitySpinner.Value;
            app.Plot3DBandStructureButton.Enable = "on";
            app.MeshDensitySpinner.Enable = "on";
        end

        % Value changed function: WindowMaxEditField
        function WindowMaxEditFieldValueChanged(app, event)
            value = app.WindowMaxEditField.Value;
            app.tmp.bandFinderEnergyMax = value;
        end

        % Value changed function: WindowMinEditField
        function WindowMinEditFieldValueChanged(app, event)
            value = app.WindowMinEditField.Value;
            app.tmp.bandFinderEnergyMin = value;
        end

        % Button pushed function: Plot3DBandStructureButton
        function Plot3DBandStructureButtonPushed(app, event)
            if ~app.HoldOnCheckBox.Value
                cla(app.bandStructure_3D);
            end
            
            % Data preparation
            bandIndexSelection = app.EIGENVAL_3D.bandIndexSelection;
            meshDensity = app.tmp.meshDensity;
            kzPlaneSelection = app.EIGENVAL_3D.kzPlaneSelection;
            kzPlaneSelection = kzPlaneSelection*app.EIGENVAL_3D.transMat;
            kzPlaneSelection = kzPlaneSelection(3);
            kpointsNumIdx = abs(app.EIGENVAL_3D.kpointsRecipMesh (3, :) - kzPlaneSelection) < 1e-6;                      
            app.EIGENVAL_3D.kpointsRecipMeshSelected = app.EIGENVAL_3D.kpointsRecipMesh (:, kpointsNumIdx);
            % Select the bandEigenValue from the index recorded in the
            % variable app.Eigenval_3D.kpointsRecipMesh(4, :)
            app.EIGENVAL_3D.bandeigenvalueSelected = zeros(1, length(app.EIGENVAL_3D.kpointsRecipMeshSelected));
            for numIdx = 1: length(app.EIGENVAL_3D.kpointsRecipMeshSelected)
                app.EIGENVAL_3D.bandeigenvalueSelected(numIdx) = app.EIGENVAL_3D.bandEigenvalue(bandIndexSelection, app.EIGENVAL_3D.kpointsRecipMeshSelected(4, numIdx));
            end
            % Projection to the 1st Brillouin Zone
            app.EIGENVAL_3D.bandeigenvalueSelected(~app.EIGENVAL_3D.brillouinZoneIndex) = NaN;
            % Determine the plot k-mesh boundary
            kxMin = min(app.EIGENVAL_3D.kpointsRecipMeshSelected(1, :));
            kxMax = max(app.EIGENVAL_3D.kpointsRecipMeshSelected(1, :));
            kyMin = min(app.EIGENVAL_3D.kpointsRecipMeshSelected(2, :));
            kyMax = max(app.EIGENVAL_3D.kpointsRecipMeshSelected(2, :));
            % kpoints mesh creatation using VASP Kpoints data
            [app.EIGENVAL_3D.xmesh, app.EIGENVAL_3D.ymesh] = meshgrid(linspace(kxMin, kxMax, meshDensity), linspace(kyMin, kyMax, meshDensity));
            app.EIGENVAL_3D.interpolatedEigenvalue = griddata(app.EIGENVAL_3D.kpointsRecipMeshSelected(1, :), app.EIGENVAL_3D.kpointsRecipMeshSelected(2, :), ...
                app.EIGENVAL_3D.bandeigenvalueSelected, app.EIGENVAL_3D.xmesh, app.EIGENVAL_3D.ymesh);
                bandplot = mesh(app.bandStructure_3D, app.EIGENVAL_3D.xmesh, app.EIGENVAL_3D.ymesh, app.EIGENVAL_3D.interpolatedEigenvalue);
            % Exports plot data
            app.figureData.bandStructure_3D.bandIndexSelection = bandIndexSelection;
            app.figureData.bandStructure_3D.kzPlaneSelection = kzPlaneSelection;
            app.figureData.bandStructure_3D.kpointsRecipMeshSelected = app.EIGENVAL_3D.kpointsRecipMeshSelected;
            app.figureData.bandStructure_3D.bandeigenvalueSelected = app.EIGENVAL_3D.bandeigenvalueSelected;
            app.figureData.bandStructure_3D.xmesh = app.EIGENVAL_3D.xmesh;
            app.figureData.bandStructure_3D.ymesh = app.EIGENVAL_3D.ymesh;
            app.figureData.bandStructure_3D.interpolatedEigenvalue = app.EIGENVAL_3D.interpolatedEigenvalue;
            app.figureData.bandStructure_3D.figure = bandplot;
            % Z-range settings
            app.bandStructure_3D.XLabel.String = "K_x";
            app.bandStructure_3D.YLabel.String = "K_y";
            app.bandStructure_3D.ZLabel.String = "Energy (eV)";
            app.ZminSpinner.Enable = "on";
            app.ZmaxSpinner.Enable = "on";
            app.tmp.bandStructure_3DZlimit = app.bandStructure_3D.ZLim;
            app.ZminSpinner.Value = app.tmp.bandStructure_3DZlimit(1);
            app.ZmaxSpinner.Value = app.tmp.bandStructure_3DZlimit(2);
            % Enable Hubbard Model Fitting
            app.OpenHubbardModelFitButton.Enable = "on";
        end

        % Value changed function: MeshDensitySpinner
        function MeshDensitySpinnerValueChanged(app, event)
            value = app.MeshDensitySpinner.Value;
            app.tmp.meshDensity = value;
        end

        % Value changed function: BandIndexDropDown
        function BandIndexDropDownValueChanged(app, event)
            value = app.BandIndexDropDown.Value;
            app.EIGENVAL_3D.bandIndexSelection = str2double(value);
        end

        % Value changed function: KzplaneDropDown
        function KzplaneDropDownValueChanged(app, event)
            value = app.KzplaneDropDown.Value;
            app.EIGENVAL_3D.kzPlaneSelection = str2double(value);
        end

        % Value changed function: ZmaxSpinner
        function ZmaxSpinnerValueChanged(app, event)
            value = app.ZmaxSpinner.Value;
            app.tmp.bandStructure_3DZlimit(2) = value;
            app.bandStructure_3D.ZLim = app.tmp.bandStructure_3DZlimit;
        end

        % Value changed function: ZminSpinner
        function ZminSpinnerValueChanged(app, event)
            value = app.ZminSpinner.Value;
            app.tmp.bandStructure_3DZlimit(1) = value;
            app.bandStructure_3D.ZLim = app.tmp.bandStructure_3DZlimit;
            
        end

        % Value changed function: HoldOnCheckBox
        function HoldOnCheckBoxValueChanged(app, event)
            value = app.HoldOnCheckBox.Value;
            if value
                hold(app.bandStructure_3D,"on");
                app.FermiSurfaceCheckBox.Enable = "on";
            else
                hold(app.bandStructure_3D,"off");
                app.FermiSurfaceCheckBox.Value = 0;
                FermiSurfaceCheckBoxValueChanged(app);
                app.FermiSurfaceCheckBox.Enable = "off";
            end
        end

        % Value changed function: FermiSurfaceCheckBox
        function FermiSurfaceCheckBoxValueChanged(app, event)
            value = app.FermiSurfaceCheckBox.Value;
            if value
                fermiZmeshSize = size(app.EIGENVAL_3D.interpolatedEigenvalue);
                fermiZmesh = zeros (fermiZmeshSize);
                app.EIGENVAL_3D.fermiSurface = mesh(app.bandStructure_3D, app.EIGENVAL_3D.xmesh, app.EIGENVAL_3D.ymesh, fermiZmesh, ...
                    'FaceAlpha', '0.2', 'EdgeColor', [0.57 0.57 0.57]);
            else
                app.EIGENVAL_3D.fermiSurface.Visible = "off";
            end
        end

        % Button pushed function: OpenHubbardModelFitButton
        function OpenHubbardModelFitButtonPushed(app, event)
            app.OpenHubbardModelFitButton.Enable = "off";
            app.hubbardFittingDialog = hubbardFitting(app, app.EIGENVAL_3D);
        end

        % Close request function: VASPPlotterUIFigure
        function VASPPlotterUIFigureCloseRequest(app, event)
            delete(app.hubbardFittingDialog);
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VASPPlotterUIFigure = uifigure('Visible', 'off');
            app.VASPPlotterUIFigure.Name = 'VASP Plotter';
            app.VASPPlotterUIFigure.CloseRequestFcn = createCallbackFcn(app, @VASPPlotterUIFigureCloseRequest, true);
            app.VASPPlotterUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.VASPPlotterUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.FileStatusTab = uitab(app.TabGroup);
            app.FileStatusTab.Title = 'File Status';
            app.INCARLabel = uilabel(app.FileStatusTab);
            app.INCARLabel.Text = 'INCAR';
            app.LampIncar = uilamp(app.FileStatusTab);
            app.POSCARLabel = uilabel(app.FileStatusTab);
            app.POSCARLabel.Text = 'POSCAR';
            app.LampPoscar = uilamp(app.FileStatusTab);
            app.DOSCARLabel = uilabel(app.FileStatusTab);
            app.DOSCARLabel.Text = 'DOSCAR';
            app.LampDoscar = uilamp(app.FileStatusTab);
            app.EIGVALLabel = uilabel(app.FileStatusTab);
            app.EIGVALLabel.Text = 'EIGVAL';
            app.LampEigenval = uilamp(app.FileStatusTab);
            app.KPOINTSLabel = uilabel(app.FileStatusTab);
            app.KPOINTSLabel.Text = 'KPOINTS';
            app.LampKpoints = uilamp(app.FileStatusTab);
            app.SmoothSettingsTab = uitab(app.TabGroup);
            app.SmoothSettingsTab.Title = 'Smooth Settings';
            app.MethodButtonGroup = uibuttongroup(app.SmoothSettingsTab);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.movmeanButton = uiradiobutton(app.MethodButtonGroup);
            app.movmeanButton.Tooltip = {'Moving average over each window of A. This method is useful for reducing periodic trends in data.'};
            app.movmeanButton.Text = 'movmean';
            app.movmeanButton.Value = true;
            app.movmedianButton = uiradiobutton(app.MethodButtonGroup);
            app.movmedianButton.Tooltip = {'Moving median over each window of A. This method is useful for reducing periodic trends in data when outliers are present.'};
            app.movmedianButton.Text = 'movmedian';
            app.gaussianButton = uiradiobutton(app.MethodButtonGroup);
            app.gaussianButton.Tooltip = {'Gaussian-weighted moving average over each window of A.'};
            app.gaussianButton.Text = 'gaussian';
            app.lowessButton = uiradiobutton(app.MethodButtonGroup);
            app.lowessButton.Tooltip = {'Linear regression over each window of A. This method can be computationally expensive'; ' but results in fewer discontinuities.'};
            app.lowessButton.Text = 'lowess';
            app.loessButton = uiradiobutton(app.MethodButtonGroup);
            app.loessButton.Tooltip = {'Quadratic regression over each window of A. This method is slightly more computationally expensive than ''lowess''.'};
            app.loessButton.Text = 'loess';
            app.rlowessButton = uiradiobutton(app.MethodButtonGroup);
            app.rlowessButton.Tooltip = {' Robust linear regression over each window of A. This method is a more computationally expensive version of the method ''lowess'''; ' but it is more robust to outliers.'};
            app.rlowessButton.Text = 'rlowess';
            app.rloessButton = uiradiobutton(app.MethodButtonGroup);
            app.rloessButton.Tooltip = {'Robust quadratic regression over each window of A. This method is a more computationally expensive version of the method ''loess'''; ' but it is more robust to outliers.'};
            app.rloessButton.Text = 'rloess';
            app.sgolayButton = uiradiobutton(app.MethodButtonGroup);
            app.sgolayButton.Tooltip = {'Savitzky-Golay filter'; ' which smooths according to a quadratic polynomial that is fitted over each window of A. This method can be more effective than other methods when the data varies rapidly.'};
            app.sgolayButton.Text = 'sgolay';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.INCARTab = uitab(app.TabGroup2);
            app.INCARTab.Title = 'INCAR';
            app.IncarData = uitable(app.INCARTab);
            app.IncarData.ColumnName = {'Flag Name'; 'Flag Value'};
            app.IncarData.RowName = {};
            app.IncarData.ColumnEditable = [true true];
            app.IncarData.DisplayDataChangedFcn = createCallbackFcn(app, @IncarDataDisplayDataChanged, true);
            app.POSCARTab = uitab(app.TabGroup2);
            app.POSCARTab.Title = 'POSCAR';
            app.PoscarData = uitable(app.POSCARTab);
            app.PoscarData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.PoscarData.RowName = {};
            app.KPOINTSTab = uitab(app.TabGroup2);
            app.KPOINTSTab.Title = 'KPOINTS';
            app.KpointsData = uitable(app.KPOINTSTab);
            app.KpointsData.ColumnName = {'x'; 'y'; 'z'; 'Label'};
            app.KpointsData.RowName = {};
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = 'Reset';
            app.ExportFigureDataButton = uibutton(app.LeftPanel, 'push');
            app.ExportFigureDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureDataButton4Pushed, true);
            app.ExportFigureDataButton.Text = 'Export Figure Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.bandStructure = uiaxes(app.RightPanel);
            title(app.bandStructure, 'Band Structure')
            xlabel(app.bandStructure, '')
            ylabel(app.bandStructure, 'Energy (eV)')
            app.PlotBandStructureButton = uibutton(app.RightPanel, 'push');
            app.PlotBandStructureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBandStructureButtonPushed, true);
            app.PlotBandStructureButton.Text = 'Plot band structure';
            app.SaveasButton = uibutton(app.RightPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.YmaxSpinnerLabel = uilabel(app.RightPanel);
            app.YmaxSpinnerLabel.Text = 'Ymax';
            app.YmaxSpinner = uispinner(app.RightPanel);
            app.YmaxSpinner.ValueChangingFcn = createCallbackFcn(app, @YmaxSpinnerValueChanging, true);
            app.YmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @YmaxSpinnerValueChanged, true);
            app.YminSpinnerLabel = uilabel(app.RightPanel);
            app.YminSpinnerLabel.Text = 'Ymin';
            app.YminSpinner = uispinner(app.RightPanel);
            app.YminSpinner.ValueChangingFcn = createCallbackFcn(app, @YminSpinnerValueChanging, true);
            app.YminSpinner.ValueChangedFcn = createCallbackFcn(app, @YminSpinnerValueChanged, true);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.TotalDOSTab = uitab(app.TabGroup3);
            app.TotalDOSTab.Title = 'Total DOS';
            app.totalDos = uiaxes(app.TotalDOSTab);
            title(app.totalDos, 'Total DOS')
            xlabel(app.totalDos, 'Energy (eV)')
            ylabel(app.totalDos, '')
            app.PlotTotalDosButton = uibutton(app.TotalDOSTab, 'push');
            app.PlotTotalDosButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTotalDosButtonPushed, true);
            app.PlotTotalDosButton.Text = 'Plot DOS';
            app.SaveTotalDosasButton = uibutton(app.TotalDOSTab, 'push');
            app.SaveTotalDosasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTotaltDosasButtonPushed, true);
            app.SaveTotalDosasButton.Text = 'Save as';
            app.YmaxSpinner_2Label = uilabel(app.TotalDOSTab);
            app.YmaxSpinner_2Label.Text = 'Ymax';
            app.TotalDosYmaxSpinner = uispinner(app.TotalDOSTab);
            app.TotalDosYmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalDosYmaxSpinnerValueChanged, true);
            app.YminSpinner_2Label = uilabel(app.TotalDOSTab);
            app.YminSpinner_2Label.Text = 'Ymin';
            app.TotalDosYminSpinner = uispinner(app.TotalDOSTab);
            app.TotalDosYminSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalDosYminSpinnerValueChanged, true);
            app.XmaxSpinnerLabel = uilabel(app.TotalDOSTab);
            app.XmaxSpinnerLabel.Text = 'Xmax';
            app.TotalDosXmaxSpinner = uispinner(app.TotalDOSTab);
            app.TotalDosXmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalDosXmaxSpinnerValueChanged, true);
            app.XminSpinnerLabel = uilabel(app.TotalDOSTab);
            app.XminSpinnerLabel.Text = 'Xmin';
            app.TotalDosXminSpinner = uispinner(app.TotalDOSTab);
            app.TotalDosXminSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalDosXminSpinnerValueChanged, true);
            app.SmoothTotalDosButton = uibutton(app.TotalDOSTab, 'push');
            app.SmoothTotalDosButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothTotalDosButtonPushed, true);
            app.SmoothTotalDosButton.Enable = 'off';
            app.SmoothTotalDosButton.Text = 'Smooth Data';
            app.ProjectedDOSTab = uitab(app.TabGroup3);
            app.ProjectedDOSTab.Title = 'Projected DOS';
            app.ElementDropDownLabel = uilabel(app.ProjectedDOSTab);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.ProjectedDOSTab);
            app.ElementDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDropDownValueChanged, true);
            app.projectDos = uiaxes(app.ProjectedDOSTab);
            title(app.projectDos, 'Projected DOS')
            xlabel(app.projectDos, 'Energy (eV)')
            ylabel(app.projectDos, '')
            app.PlotProjectedDosButton = uibutton(app.ProjectedDOSTab, 'push');
            app.PlotProjectedDosButton.ButtonPushedFcn = createCallbackFcn(app, @PlotProjectedDosButtonPushed, true);
            app.PlotProjectedDosButton.Text = 'Plot DOS';
            app.SaveProjectedDosasButton = uibutton(app.ProjectedDOSTab, 'push');
            app.SaveProjectedDosasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectDosasButtonPushed, true);
            app.SaveProjectedDosasButton.Text = 'Save as';
            app.YmaxSpinner_3Label = uilabel(app.ProjectedDOSTab);
            app.YmaxSpinner_3Label.Text = 'Ymax';
            app.ProjectedDosYmaxSpinner = uispinner(app.ProjectedDOSTab);
            app.ProjectedDosYmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ProjectedDosYmaxSpinnerValueChanged, true);
            app.YminSpinner_3Label = uilabel(app.ProjectedDOSTab);
            app.YminSpinner_3Label.Text = 'Ymin';
            app.ProjectedDosYminSpinner = uispinner(app.ProjectedDOSTab);
            app.ProjectedDosYminSpinner.ValueChangedFcn = createCallbackFcn(app, @ProjectedDosYminSpinnerValueChanged, true);
            app.XmaxSpinner_2Label = uilabel(app.ProjectedDOSTab);
            app.XmaxSpinner_2Label.Text = 'Xmax';
            app.ProjectedDosXmaxSpinner = uispinner(app.ProjectedDOSTab);
            app.ProjectedDosXmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ProjectedDosXmaxSpinnerValueChanged, true);
            app.XminSpinner_2Label = uilabel(app.ProjectedDOSTab);
            app.XminSpinner_2Label.Text = 'Xmin';
            app.ProjectedDosXminSpinner = uispinner(app.ProjectedDOSTab);
            app.ProjectedDosXminSpinner.ValueChangedFcn = createCallbackFcn(app, @ProjectedDosXminSpinnerValueChanged, true);
            app.ProjectedDosSmoothButton = uibutton(app.ProjectedDOSTab, 'push');
            app.ProjectedDosSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectedDosSmoothButtonPushed, true);
            app.ProjectedDosSmoothButton.Enable = 'off';
            app.ProjectedDosSmoothButton.Text = 'Smooth Data';
            app.PlotSegmentsPanel = uipanel(app.ProjectedDOSTab);
            app.PlotSegmentsPanel.Title = 'Plot Segments';
            app.CheckBox_1 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.PlotSegmentsPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Value = true;
            app.QuickSelectionButtonGroup = uibuttongroup(app.PlotSegmentsPanel);
            app.QuickSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QuickSelectionButtonGroupSelectionChanged, true);
            app.QuickSelectionButtonGroup.Title = 'Quick Selection';
            app.SelectallButton = uitogglebutton(app.QuickSelectionButtonGroup);
            app.SelectallButton.Text = 'Select all';
            app.SelectallButton.Value = true;
            app.ManualButton = uitogglebutton(app.QuickSelectionButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.DeselectallButton = uitogglebutton(app.QuickSelectionButtonGroup);
            app.DeselectallButton.Text = 'Deselect all';
            app.FatBandTab = uitab(app.TabGroup3);
            app.FatBandTab.Title = 'Fat Band';
            app.AtomNumberDropDownLabel = uilabel(app.FatBandTab);
            app.AtomNumberDropDownLabel.Text = 'Atom Number';
            app.AtomNumberDropDown = uidropdown(app.FatBandTab);
            app.AtomNumberDropDown.Items = {'Option 1'};
            app.AtomNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @AtomNumberDropDownValueChanged, true);
            app.PlotSegmentsPanel_2 = uipanel(app.FatBandTab);
            app.PlotSegmentsPanel_2.Title = 'Plot Segments';
            app.CheckBox_10 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_17.ValueChangedFcn = createCallbackFcn(app, @CheckBox_17ValueChanged, true);
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.PlotSegmentsPanel_2);
            app.CheckBox_18.ValueChangedFcn = createCallbackFcn(app, @CheckBox_18ValueChanged, true);
            app.CheckBox_18.Value = true;
            app.QuickSelectionButtonGroup_2 = uibuttongroup(app.PlotSegmentsPanel_2);
            app.QuickSelectionButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @QuickSelectionButtonGroup_2SelectionChanged, true);
            app.QuickSelectionButtonGroup_2.Title = 'Quick Selection';
            app.SelectallButton_2 = uitogglebutton(app.QuickSelectionButtonGroup_2);
            app.SelectallButton_2.Text = 'Select all';
            app.SelectallButton_2.Value = true;
            app.ManualButton_2 = uitogglebutton(app.QuickSelectionButtonGroup_2);
            app.ManualButton_2.Text = 'Manual';
            app.DeselectallButton_2 = uitogglebutton(app.QuickSelectionButtonGroup_2);
            app.DeselectallButton_2.Text = 'Deselect all';
            app.FatBand = uiaxes(app.FatBandTab);
            title(app.FatBand, 'Band Structure')
            xlabel(app.FatBand, '')
            ylabel(app.FatBand, 'Energy (eV)')
            app.PlotFatBandButton = uibutton(app.FatBandTab, 'push');
            app.PlotFatBandButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFatBandButtonPushed, true);
            app.PlotFatBandButton.Text = 'Plot band structure';
            app.ImportProcarButton = uibutton(app.FatBandTab, 'push');
            app.ImportProcarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportProcarButtonPushed, true);
            app.ImportProcarButton.Text = 'Import PROCAR';
            app.FatbandscalingParameterEditFieldLabel = uilabel(app.FatBandTab);
            app.FatbandscalingParameterEditFieldLabel.Text = 'Fat band scaling Parameter';
            app.FatbandscalingParameterEditField = uieditfield(app.FatBandTab, 'numeric');
            app.FatbandscalingParameterEditField.Limits = [0 Inf];
            app.FatbandscalingParameterEditField.ValueChangedFcn = createCallbackFcn(app, @FatbandscalingParameterEditFieldValueChanged, true);
            app.FatbandscalingParameterEditField.Value = 10;
            app.Wannier90FitTab = uitab(app.TabGroup3);
            app.Wannier90FitTab.Title = 'Wannier90 Fit';
            app.WannierFitFigure = uiaxes(app.Wannier90FitTab);
            title(app.WannierFitFigure, 'Wannier Fit')
            xlabel(app.WannierFitFigure, '')
            ylabel(app.WannierFitFigure, 'Energy (eV)')
            app.ImportWannierFitButton = uibutton(app.Wannier90FitTab, 'push');
            app.ImportWannierFitButton.ButtonPushedFcn = createCallbackFcn(app, @ImportWannierFitButtonPushed, true);
            app.ImportWannierFitButton.Text = {'Import Wannier Fit'; '(wannie90_band.dat)'};
            app.PlotWannierFitButton = uibutton(app.Wannier90FitTab, 'push');
            app.PlotWannierFitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWannierFitButtonPushed, true);
            app.PlotWannierFitButton.Text = 'Plot Wannier Fit';
            app.ScatterSizeEditFieldLabel = uilabel(app.Wannier90FitTab);
            app.ScatterSizeEditFieldLabel.Text = 'Scatter Size';
            app.ScatterSizeEditField = uieditfield(app.Wannier90FitTab, 'numeric');
            app.ScatterSizeEditField.Limits = [0 Inf];
            app.ScatterSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ScatterSizeEditFieldValueChanged, true);
            app.ScatterSizeEditField.Value = 12;
            app.DBandStructureTab = uitab(app.TabGroup3);
            app.DBandStructureTab.Title = '3D Band Structure';
            app.bandStructure_3D = uiaxes(app.DBandStructureTab);
            title(app.bandStructure_3D, 'Band Structure')
            xlabel(app.bandStructure_3D, '')
            ylabel(app.bandStructure_3D, '')
            app.ImportBandStructure_3DButton = uibutton(app.DBandStructureTab, 'push');
            app.ImportBandStructure_3DButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBandStructure_3DButtonPushed, true);
            app.ImportBandStructure_3DButton.Text = {'Import Static Full-BZ'; 'EIGENVAL File'};
            app.Plot3DBandStructureButton = uibutton(app.DBandStructureTab, 'push');
            app.Plot3DBandStructureButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DBandStructureButtonPushed, true);
            app.Plot3DBandStructureButton.Enable = 'off';
            app.Plot3DBandStructureButton.Text = 'Plot 3D Band Structure';
            app.BandIndexFinderPanel = uipanel(app.DBandStructureTab);
            app.BandIndexFinderPanel.Title = 'Band Index Finder';
            app.WindowMaxEditFieldLabel = uilabel(app.BandIndexFinderPanel);
            app.WindowMaxEditFieldLabel.Text = 'Window Max';
            app.WindowMaxEditField = uieditfield(app.BandIndexFinderPanel, 'numeric');
            app.WindowMaxEditField.ValueChangedFcn = createCallbackFcn(app, @WindowMaxEditFieldValueChanged, true);
            app.WindowMaxEditField.Enable = 'off';
            app.WindowMaxEditField.Value = 8;
            app.WindowMinEditFieldLabel = uilabel(app.BandIndexFinderPanel);
            app.WindowMinEditFieldLabel.Text = 'Window Min';
            app.WindowMinEditField = uieditfield(app.BandIndexFinderPanel, 'numeric');
            app.WindowMinEditField.ValueChangedFcn = createCallbackFcn(app, @WindowMinEditFieldValueChanged, true);
            app.WindowMinEditField.Enable = 'off';
            app.WindowMinEditField.Value = -8;
            app.FindBandButton = uibutton(app.BandIndexFinderPanel, 'push');
            app.FindBandButton.ButtonPushedFcn = createCallbackFcn(app, @FindBandButtonPushed, true);
            app.FindBandButton.Enable = 'off';
            app.FindBandButton.Text = 'Find Band';
            app.BandIndexDropDownLabel = uilabel(app.DBandStructureTab);
            app.BandIndexDropDownLabel.Text = 'Band Index';
            app.BandIndexDropDown = uidropdown(app.DBandStructureTab);
            app.BandIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @BandIndexDropDownValueChanged, true);
            app.BandIndexDropDown.Enable = 'off';
            app.KzplaneDropDownLabel = uilabel(app.DBandStructureTab);
            app.KzplaneDropDownLabel.Text = 'Kz-plane';
            app.KzplaneDropDown = uidropdown(app.DBandStructureTab);
            app.KzplaneDropDown.ValueChangedFcn = createCallbackFcn(app, @KzplaneDropDownValueChanged, true);
            app.KzplaneDropDown.Enable = 'off';
            app.ZmaxSpinnerLabel = uilabel(app.DBandStructureTab);
            app.ZmaxSpinnerLabel.Text = 'Z-max';
            app.ZmaxSpinner = uispinner(app.DBandStructureTab);
            app.ZmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ZmaxSpinnerValueChanged, true);
            app.ZmaxSpinner.Enable = 'off';
            app.ZminSpinnerLabel = uilabel(app.DBandStructureTab);
            app.ZminSpinnerLabel.Text = 'Z-min';
            app.ZminSpinner = uispinner(app.DBandStructureTab);
            app.ZminSpinner.ValueChangedFcn = createCallbackFcn(app, @ZminSpinnerValueChanged, true);
            app.ZminSpinner.Enable = 'off';
            app.MeshDensitySpinnerLabel = uilabel(app.DBandStructureTab);
            app.MeshDensitySpinnerLabel.Text = 'Mesh Density';
            app.MeshDensitySpinner = uispinner(app.DBandStructureTab);
            app.MeshDensitySpinner.Limits = [0 Inf];
            app.MeshDensitySpinner.ValueChangedFcn = createCallbackFcn(app, @MeshDensitySpinnerValueChanged, true);
            app.MeshDensitySpinner.Enable = 'off';
            app.MeshDensitySpinner.Value = 100;
            app.HoldOnCheckBox = uicheckbox(app.DBandStructureTab);
            app.HoldOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldOnCheckBoxValueChanged, true);
            app.HoldOnCheckBox.Enable = 'off';
            app.HoldOnCheckBox.Text = 'Hold On';
            app.FermiSurfaceCheckBox = uicheckbox(app.DBandStructureTab);
            app.FermiSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @FermiSurfaceCheckBoxValueChanged, true);
            app.FermiSurfaceCheckBox.Enable = 'off';
            app.FermiSurfaceCheckBox.Text = 'Fermi Surface';
            app.OpenHubbardModelFitButton = uibutton(app.DBandStructureTab, 'push');
            app.OpenHubbardModelFitButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHubbardModelFitButtonPushed, true);
            app.OpenHubbardModelFitButton.Enable = 'off';
            app.OpenHubbardModelFitButton.Text = 'Open Hubbard Model Fit';
            % Show the figure after all components are created
            app.VASPPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VaspPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VASPPlotterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VASPPlotterUIFigure)
        end
    end
end