classdef hubbardFitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HubbardModelFittingUIFigure   matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FunctionSetupTab              matlab.ui.container.Tab
        Step1HubbardModelFunctionSetupPanel  matlab.ui.container.Panel
        hubbardFunctionDisplay        matlab.ui.control.UIAxes
        Image                         matlab.ui.control.Image
        ImportHoppingMatrixButton     matlab.ui.control.Button
        hoppingData                   matlab.ui.control.Table
        NeighbourOrderSpinnerLabel    matlab.ui.control.Label
        NeighbourOrderSpinner         matlab.ui.control.Spinner
        ConfirmSetupButton            matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SetupModeButtonGroup          matlab.ui.container.ButtonGroup
        ImportFromFileButton_2        matlab.ui.control.ToggleButton
        ManualButton_2                matlab.ui.control.ToggleButton
        SaveequationtotheclipboardButton  matlab.ui.control.Button
        Step2HoppingparameterInitializationPanel  matlab.ui.container.Panel
        hoppingParameter              matlab.ui.control.Table
        ImportHoppingParameterButton  matlab.ui.control.Button
        SetupModeButtonGroup_2        matlab.ui.container.ButtonGroup
        ImportFromFileButton_3        matlab.ui.control.ToggleButton
        ManualButton_3                matlab.ui.control.ToggleButton
        t1SpinnerLabel                matlab.ui.control.Label
        t1Spinner                     matlab.ui.control.Spinner
        t2Label                       matlab.ui.control.Label
        t2Spinner                     matlab.ui.control.Spinner
        t3SpinnerLabel                matlab.ui.control.Label
        t3Spinner                     matlab.ui.control.Spinner
        t4SpinnerLabel                matlab.ui.control.Label
        t4Spinner                     matlab.ui.control.Spinner
        ConfirmSetupButton_2          matlab.ui.control.Button
        ResetButton_2                 matlab.ui.control.Button
        DeltaSpinnerLabel             matlab.ui.control.Label
        DeltaSpinner                  matlab.ui.control.Spinner
        FunctionFittingTab            matlab.ui.container.Tab
        ImportKmeshButton             matlab.ui.control.Button
        KMeshFigure                   matlab.ui.control.UIAxes
        CalculateHamiltonianButton    matlab.ui.control.Button
        DifferenceFigure              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        callingApp
        EIGENVAL_3D
        hubbardModelFunctionSetup
        hubbardModel % Description
    end
    
    methods (Access = private)
        
        function transformHoppingData(app, command)
            neighbourOrder = app.hubbardModelFunctionSetup.neighbourOrder;
            switch command
                case 'toTable'
                    app.hoppingData.Data = [];
                    for numIdx = 1: neighbourOrder
                        tmpMat = app.hubbardModelFunctionSetup.hoppingMatrixProcessed{numIdx};
                        app.hubbardModelFunctionSetup.hoppingMatrix = [app.hubbardModelFunctionSetup.hoppingMatrix; numIdx*ones(length(tmpMat), 1), tmpMat];
                    end
                    app.hoppingData.Data = array2table(app.hubbardModelFunctionSetup.hoppingMatrix);
                case 'toCell'                    
                    for numIdx = 1: neighbourOrder
                        index = app.hubbardModelFunctionSetup.hoppingMatrix(:, 1) == numIdx;
                        app.hubbardModelFunctionSetup.hoppingMatrixProcessed{numIdx} = app.hubbardModelFunctionSetup.hoppingMatrix(index, 2: end);
                    end                    
            end
        end
    
        function transformHoppingParameterData(app, command) 
            neighbourOrder = app.hubbardModelFunctionSetup.neighbourOrder;
            switch command
                case 'toTable'
                    app.hubbardModelFunctionSetup.hoppingParameterMatrix = [];
                    app.hoppingParameter.Data = [];
                    for numIdx = 1: neighbourOrder
                        tmpMat = app.hubbardModelFunctionSetup.hoppingParameter{numIdx};
                        app.hubbardModelFunctionSetup.hoppingParameterMatrix = [app.hubbardModelFunctionSetup.hoppingParameterMatrix; numIdx*ones(length(tmpMat), 1), tmpMat];
                    end
                    app.hoppingParameter.Data = array2table(app.hubbardModelFunctionSetup.hoppingParameterMatrix);
                case 'toCell'                    
                    for numIdx = 1: neighbourOrder
                        index = app.hubbardModelFunctionSetup.hoppingParameterMatrix(:, 1) == numIdx;
                        app.hubbardModelFunctionSetup.hoppingParameter{numIdx} = app.hubbardModelFunctionSetup.hoppingParameterMatrix(index, 2: end);
                    end                    
            end
        end
        
        function updateEquation(app)
            cla(app.hubbardFunctionDisplay, 'reset');
            equationStr = '$$H_{kin} = ';
            for i = 1: app.hubbardModelFunctionSetup.neighbourOrder
                if i == 1
                    equationStr = strcat(equationStr, '2t_{', mat2str(i), '}^m\left\{');
                else
                    equationStr = strcat(equationStr, '+ 2t_{', mat2str(i), '}^m\left\{');
                end
                for numIdx = 1: length(app.hubbardModelFunctionSetup.hoppingMatrixProcessed{i})/2
                    a1Parameter = app.hubbardModelFunctionSetup.hoppingMatrixProcessed{i}(numIdx, 1);
                    a2Parameter = app.hubbardModelFunctionSetup.hoppingMatrixProcessed{i}(numIdx, 2);
                    a3Parameter = app.hubbardModelFunctionSetup.hoppingMatrixProcessed{i}(numIdx, 3);
                    if a1Parameter == 0 && a2Parameter == 0 && a3Parameter == 0
                        continue
                    end
                    switch a1Parameter
                        case 0
                            a1Parameter = '';
                        case -1
                            a1Parameter = '- \vec{a_1}';
                        case 1
                            a1Parameter = '\vec{a_1}';
                        otherwise
                            a1Parameter = [mat2str(a1Parameter), '\vec{a_1}'];
                    end
                    switch a2Parameter
                        case 0
                            a2Parameter = '';
                        case -1
                            a2Parameter = '- \vec{a_2}';
                        case 1
                            a2Parameter = '+ \vec{a_2}';
                        otherwise
                            if a2Parameter > 0
                                a2Parameter = ['+ ', mat2str(a2Parameter), '\vec{a_2}'];
                            else
                                a2Parameter = ['- ', mat2str(-a2Parameter), '\vec{a_2}'];
                            end
                    end
                    switch a3Parameter
                        case 0
                            a3Parameter = '';
                        case -1
                            a3Parameter = '- \vec{a_3}';
                        case 1
                            a3Parameter = '+ \vec{a_3}';
                        otherwise
                            if a3Parameter > 0
                                a3Parameter = ['+ ', mat2str(a3Parameter), '\vec{a_3}'];
                            else
                                a3Parameter = ['- ', mat2str(-a3Parameter), '\vec{a_3}'];
                            end
                    end
                    equationStr = strcat(equationStr, '+ cos[ \vec{k} \cdot (', a1Parameter, a2Parameter, a3Parameter, ')]');
                end
                equationStr = strcat(equationStr, '\right\}\\');
            end
            equationStr = strcat(equationStr, '+ \Delta $$');
            text(app.hubbardFunctionDisplay, 'String', equationStr, 'Interpreter',"latex", "Position", [0.05 0.85]);
            app.hubbardFunctionDisplay.XTick = [];
            app.hubbardFunctionDisplay.YTick = [];
        end
        
        function initializeHoppingParameter(app)
            neighbourOrder = app.NeighbourOrderSpinner.Value;
            if app.SetupModeButtonGroup_2.Buttons(2).Value ~= 1
                app.SetupModeButtonGroup_2.Buttons(2).Value = 1;                
            end
            % Initialize Data Storage
            app.hubbardModelFunctionSetup.hoppingParameter = [];
            app.hubbardModelFunctionSetup.hoppingParameterMatrix = [];
            app.hoppingParameter.Data = [];
            for numIdx = 1: neighbourOrder
                app.hubbardModelFunctionSetup.hoppingParameter{numIdx} = 0;
            end            
            transformHoppingParameterData(app, 'toTable');
            updateHoppingParameterSliderStatus(app);
        end
        
        function updateHoppingParameterSliderStatus(app)
            neighbourOrder = app.NeighbourOrderSpinner.Value;
            slidesLogicalMat = false(4,1);
            if neighbourOrder >= length(slidesLogicalMat)
                slidesLogicalMat = true(4, 1);
            else
                slidesLogicalMat(1: neighbourOrder, 1) = true;
            end
            
            switch slidesLogicalMat(1)
                case 1
                    app.t1Spinner.Enable = "on";
                    app.t1Spinner.Value = app.hubbardModelFunctionSetup.hoppingParameter{1};
                case 0
                    app.t1Spinner.Enable = "off";
            end
            
            switch slidesLogicalMat(2)
                case 1
                    app.t2Spinner.Enable = "on";
                    app.t2Spinner.Value = app.hubbardModelFunctionSetup.hoppingParameter{2};
                case 0
                    app.t2Spinner.Enable = "off";
            end
            
            switch slidesLogicalMat(3)
                case 1
                    app.t3Spinner.Enable = "on";
                    app.t3Spinner.Value = app.hubbardModelFunctionSetup.hoppingParameter{3};
                case 0
                    app.t3Spinner.Enable = "off";
            end
            
            switch slidesLogicalMat(4)
                case 1
                    app.t4Spinner.Enable = "on";
                    app.t4Spinner.Value = app.hubbardModelFunctionSetup.hoppingParameter{4};
                case 0
                    app.t4Spinner.Enable = "off";
            end
        end
        
        function calculateDifference(app)
            % Display the energy difference between the Fitting curve and
            % the DFT curve (Delta = E_{fitting} - E_{DFT})
            app.hubbardModel.difference = app.hubbardModel.Hkin - app.hubbardModel.bandeigenvalueSelected;
            app.hubbardModel.interpolatedDifference = griddata(app.hubbardModel.kpointsRecipMeshSelected(1, :), app.hubbardModel.kpointsRecipMeshSelected(2, :), ...
                app.hubbardModel.difference, app.hubbardModel.xmesh, app.hubbardModel.ymesh);
            app.hubbardModel.differencePlot = mesh(app.DifferenceFigure, app.hubbardModel.xmesh, app.hubbardModel.ymesh, app.hubbardModel.interpolatedDifference);            
            view(app.DifferenceFigure, [0 0 90]);
        end
    end
    
    methods(Static)
        function Hkin = calculateKineticHamiltonian(transMat, kpointsRecip, hoppingParameter, hoppingMatrixProcessed)
            Hkin = 0;
            for hoppingOrder = 1: length(hoppingParameter)
                sum = 0;
                hoppingLength = length(hoppingMatrixProcessed{hoppingOrder});
                a1part = cell(1, hoppingLength);
                a2part = cell(1, hoppingLength);
                a3part = cell(1, hoppingLength);
                dotpart = zeros(1, hoppingLength);
                for numIdx = 1: hoppingLength
                    a1part{numIdx} = hoppingMatrixProcessed{hoppingOrder}(numIdx, 1).*transMat(1, :);
                    a2part{numIdx} = hoppingMatrixProcessed{hoppingOrder}(numIdx, 2).*transMat(2, :);
                    a3part{numIdx} = hoppingMatrixProcessed{hoppingOrder}(numIdx, 3).*transMat(3, :);
                    dotpart(numIdx) = dot(kpointsRecip, a1part{numIdx} + a2part{numIdx} + a3part{numIdx});
                    sum = sum + exp(1i*dotpart(numIdx));
                end
                Hkin = Hkin + hoppingParameter{hoppingOrder}*sum;
            end            
        end
    end       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, EIGENVAL_3D)
            % Write the variables to the Storage
            app.callingApp = mainApp;
            app.EIGENVAL_3D = EIGENVAL_3D;
            app.hubbardModelFunctionSetup.neighbourOrder = app.NeighbourOrderSpinner.Value;
            % Create the local variables
            neighbourOrder = app.hubbardModelFunctionSetup.neighbourOrder;
            app.hubbardModelFunctionSetup.hoppingMatrix = zeros(6*neighbourOrder, 4);
            for i = 1: neighbourOrder
                app.hubbardModelFunctionSetup.hoppingMatrix( (6*(i - 1) + 1): 6*i, 1) = i*ones(6, 1);
            end
            app.hoppingData.Data = array2table(app.hubbardModelFunctionSetup.hoppingMatrix);
            % Import lattice constant
            SCAL = str2double(app.callingApp.POSCAR.stringMat(2,1));
            a1 = str2double(app.callingApp.POSCAR.stringMat(3, 1: 3))*SCAL;
            a2 = str2double(app.callingApp.POSCAR.stringMat(4, 1: 3))*SCAL;
            a3 = str2double(app.callingApp.POSCAR.stringMat(5, 1: 3))*SCAL;
            app.hubbardModelFunctionSetup.latticeTransMat = [a1; a2; a3];
        end

        % Close request function: HubbardModelFittingUIFigure
        function HubbardModelFittingUIFigureCloseRequest(app, event)
            % Write the related variables to the exported data.
            if isfield(app.hubbardModelFunctionSetup, 'latticeTransMat')
                app.callingApp.figureData.hubbardModel.latticeTransMat = app.hubbardModelFunctionSetup.latticeTransMat;
            end
            if isfield(app.hubbardModel, 'hoppingParameter')
                app.callingApp.figureData.hubbardModel.hoppingParameter = app.hubbardModel.hoppingParameter;
            end
            if isfield(app.hubbardModel, 'hoppingMatrixProcessed')
                app.callingApp.figureData.hubbardModel.hoppingMatrixProcessed = app.hubbardModel.hoppingMatrixProcessed;
            end
            if isfield(app.hubbardModel, 'kpointsRecipMeshSelected')
                app.callingApp.figureData.hubbardModel.kpointsRecipMeshSelected = app.hubbardModel.kpointsRecipMeshSelected;
            end
            if isfield(app.hubbardModel, 'bandeigenvalueSelected')
                app.callingApp.figureData.hubbardModel.bandeigenvalueSelected = app.hubbardModel.bandeigenvalueSelected;
            end
            % Close action
            app.callingApp.OpenHubbardModelFitButton.Enable = "on";
            delete(app)            
        end

        % Value changed function: NeighbourOrderSpinner
        function NeighbourOrderSpinnerValueChanged(app, event)
            value = app.NeighbourOrderSpinner.Value;
            app.hubbardModelFunctionSetup.neighbourOrder = value;
        end

        % Cell edit callback: hoppingData
        function hoppingDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.hubbardModelFunctionSetup.hoppingMatrix(indices(1), indices(2)) = newData;
            transformHoppingData(app, 'toCell');
            updateEquation(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.hoppingData.ColumnEditable = true(1,4);
            app.NeighbourOrderSpinner.Enable = "on";
            app.hubbardModelFunctionSetup.hoppingMatrix = [];
            app.hoppingData.Data = [];
            neighbourOrder = app.hubbardModelFunctionSetup.neighbourOrder;
            app.hubbardModelFunctionSetup.hoppingMatrix = zeros(6*neighbourOrder, 4);
            for i = 1: neighbourOrder
                app.hubbardModelFunctionSetup.hoppingMatrix( (6*(i - 1) + 1): 6*i, 1) = i*ones(6, 1);
            end
            app.hoppingData.Data = array2table(app.hubbardModelFunctionSetup.hoppingMatrix);
            app.SetupModeButtonGroup.Buttons(2).Value = 1;
            app.ImportHoppingMatrixButton.Enable = "off";
        end

        % Button pushed function: ImportHoppingMatrixButton
        function ImportHoppingMatrixButtonPushed(app, event)
            % Clear variables
            app.hubbardModelFunctionSetup.hoppingMatrix = [];
            % Import Hopping data
            filename = strcat(app.callingApp.dirname,'/hopping.mat');
            if ~exist(filename,'file')
                [name, path] = uigetfile('*.mat');
                filename = strcat(path,name);
                if ~exist(filename,'file')
                    errordlg('Hopping .mat file not found','File Error');
                    clear variables;
                    return
                end
            end
            app.hubbardModelFunctionSetup.hoppingMatrixOrigin = importdata(filename);
            app.hubbardModelFunctionSetup.hoppingMatrixOrigin(1) = [];
            neighbourOrder = app.hubbardModelFunctionSetup.neighbourOrder;
            app.hubbardModelFunctionSetup.hoppingMatrixProcessed(1: neighbourOrder) = app.hubbardModelFunctionSetup.hoppingMatrixOrigin(1: neighbourOrder);
            if length(app.hubbardModelFunctionSetup.hoppingMatrixProcessed{1}(1, :)) == 2
                for numIdx = 1: neighbourOrder
                    app.hubbardModelFunctionSetup.hoppingMatrixProcessed{1, numIdx} = [app.hubbardModelFunctionSetup.hoppingMatrixProcessed{1, numIdx}, ...
                        zeros(length(app.hubbardModelFunctionSetup.hoppingMatrixProcessed{1, numIdx}), 1)];
                end
            end
            transformHoppingData(app, 'toTable');
            updateEquation(app);
        end

        % Selection changed function: SetupModeButtonGroup
        function SetupModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SetupModeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Import From File'
                    app.hubbardModelFunctionSetup.hoppingMatrix = [];
                    app.hoppingData.Data = [];
                    app.ImportHoppingMatrixButton.Enable = "on";
                case 'Manual'
                    app.ImportHoppingMatrixButton.Enable = "off";
            end
        end

        % Button pushed function: ConfirmSetupButton
        function ConfirmSetupButtonPushed(app, event)
            % Panel State property changed
            app.ImportHoppingMatrixButton.Enable = "off";
            app.NeighbourOrderSpinner.Enable = "off";
            app.hoppingData.ColumnEditable = false(1,4);
            app.ResetButton.Enable = "off";
            app.NeighbourOrderSpinner.Enable = "off";
            app.ConfirmSetupButton.Enable = "off";
            app.ImportFromFileButton_2.Enable = "off";
            app.ManualButton_2.Enable = "off";
            % Initialize Hopping Parameter Panel
            app.ImportFromFileButton_3.Enable = "on";
            app.ManualButton_3.Enable = "on";
            app.ConfirmSetupButton_2.Enable = "on";
            app.ResetButton_2.Enable = "on";
            app.hoppingParameter.Enable = "on";
            app.DeltaSpinner.Enable = "on";
            initializeHoppingParameter(app);
        end

        % Button pushed function: SaveequationtotheclipboardButton
        function SaveequationtotheclipboardButtonPushed(app, event)
            if ~isempty(app.hubbardFunctionDisplay.Children)
                equationStr = app.hubbardFunctionDisplay.Children.String;
                clipboard("copy", equationStr);
            end
        end

        % Selection changed function: SetupModeButtonGroup_2
        function SetupModeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SetupModeButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'Import From File'
                    app.hubbardModelFunctionSetup.hoppingParameterMatrix = [];
                    app.hoppingParameter.Data = [];
                    app.ImportHoppingParameterButton.Enable = "on";
                case 'Manual'
                    app.ImportHoppingParameterButton.Enable = "off";
            end
        end

        % Button pushed function: ImportHoppingParameterButton
        function ImportHoppingParameterButtonPushed(app, event)
            % Import Hopping data
            filename = strcat(app.callingApp.dirname,'/hoppingParameter.mat');
            if ~exist(filename,'file')
                [name, path] = uigetfile('*.mat');
                filename = strcat(path,name);
                if ~exist(filename,'file')
                    errordlg('Hopping parameter .mat file not found','File Error');
                    clear variables;
                    return
                end
            end
            app.hubbardModelFunctionSetup.hoppingParameterOrigin = importdata(filename);
            if length(app.hubbardModelFunctionSetup.hoppingParameterOrigin) < app.NeighbourOrderSpinner.Value
                errordlg('Dimension mismatch found in importing parameter matrix with Hopping matrix', 'File Error');
                return
            else
                app.hubbardModelFunctionSetup.hoppingParameter = app.hubbardModelFunctionSetup.hoppingParameterOrigin(1: app.NeighbourOrderSpinner.Value);
            end
            transformHoppingParameterData(app, 'toTable');
            updateHoppingParameterSliderStatus(app);
        end

        % Cell edit callback: hoppingParameter
        function hoppingParameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.hubbardModelFunctionSetup.hoppingParameterMatrix(indices(1), indices(2)) = newData;
            transformHoppingParameterData(app, 'toCell');
            updateHoppingParameterSliderStatus(app);
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.SetupModeButtonGroup_2.Buttons(2).Value = 1;
            initializeHoppingParameter(app);
        end

        % Value changed function: t1Spinner
        function t1SpinnerValueChanged(app, event)
            value = app.t1Spinner.Value;
            app.hubbardModelFunctionSetup.hoppingParameter{1} = value;
            transformHoppingParameterData(app, 'toTable');
        end

        % Value changed function: t2Spinner
        function t2SpinnerValueChanged(app, event)
            value = app.t2Spinner.Value;
            app.hubbardModelFunctionSetup.hoppingParameter{2} = value;
            transformHoppingParameterData(app, 'toTable');
        end

        % Value changed function: t3Spinner
        function t3SpinnerValueChanged(app, event)
            value = app.t3Spinner.Value;
            app.hubbardModelFunctionSetup.hoppingParameter{3} = value;
            transformHoppingParameterData(app, 'toTable');
        end

        % Value changed function: t4Spinner
        function t4SpinnerValueChanged(app, event)
            value = app.t4Spinner.Value;
            app.hubbardModelFunctionSetup.hoppingParameter{4} = value;
            transformHoppingParameterData(app, 'toTable');
        end

        % Button pushed function: ConfirmSetupButton_2
        function ConfirmSetupButton_2Pushed(app, event)
            % UI control
            app.hoppingParameter.ColumnEditable = false(1, 2);
            app.ImportHoppingParameterButton.Enable = "off";
            app.ConfirmSetupButton_2.Enable = "off";
            app.ResetButton_2.Enable = "off";
            app.t1Spinner.Enable = "off";
            app.t2Spinner.Enable = "off";
            app.t3Spinner.Enable = "off";
            app.t4Spinner.Enable = "off";
            app.ImportFromFileButton_3.Enable = "off";
            app.ManualButton_3.Enable = "off";
            % Store the setup value
            app.hubbardModel.hoppingMatrixProcessed = app.hubbardModelFunctionSetup.hoppingMatrixProcessed;
            app.hubbardModel.hoppingParameter = app.hubbardModelFunctionSetup.hoppingParameter;
        end

        % Button pushed function: ImportKmeshButton
        function ImportKmeshButtonPushed(app, event)
            app.hubbardModel.kpointsRecipMeshSelected = app.callingApp.figureData.bandStructure_3D.kpointsRecipMeshSelected;
            app.hubbardModel.bandeigenvalueSelected = app.callingApp.figureData.bandStructure_3D.bandeigenvalueSelected;
            app.hubbardModel.xmesh = app.callingApp.figureData.bandStructure_3D.xmesh;
            app.hubbardModel.ymesh = app.callingApp.figureData.bandStructure_3D.ymesh;
            app.hubbardModel.interpolatedEigenvalue = app.callingApp.figureData.bandStructure_3D.interpolatedEigenvalue;
            selectedNumIdx = ~isnan(app.hubbardModel.bandeigenvalueSelected);
            scatter(app.KMeshFigure, app.hubbardModel.kpointsRecipMeshSelected(1, selectedNumIdx), app.hubbardModel.kpointsRecipMeshSelected(2, selectedNumIdx), 6);
        end

        % Button pushed function: CalculateHamiltonianButton
        function CalculateHamiltonianButtonPushed(app, event)
            selectedNumIdx = ~isnan(app.hubbardModel.bandeigenvalueSelected);
            notNanRecipMesh = app.hubbardModel.kpointsRecipMeshSelected(1: 3, selectedNumIdx);
            Delta = app.hubbardModelFunctionSetup.hoppingParameterDelta;
            FermiLevel = app.callingApp.DOSCAR.fermiLevel;
            Hkin = zeros(1, length(notNanRecipMesh));
            for numIdx = 1: length(notNanRecipMesh)
                Hkin(numIdx) = app.calculateKineticHamiltonian(app.hubbardModelFunctionSetup.latticeTransMat, notNanRecipMesh(1:3, numIdx), ...
                    app.hubbardModel.hoppingParameter, app.hubbardModel.hoppingMatrixProcessed) + Delta - FermiLevel;
            end
            % Transform the Hkin from complex to real, write the
            % calculated Hkin matrix
            app.hubbardModel.Hkin = nan(1, length(app.hubbardModel.bandeigenvalueSelected));
            if prod(imag(Hkin) < 1e-10)
                app.hubbardModel.Hkin(selectedNumIdx) = real(Hkin);
            end            
            cla(app.KMeshFigure);
            scatter3(app.KMeshFigure, notNanRecipMesh(1, :), notNanRecipMesh(2, :), app.hubbardModel.Hkin(selectedNumIdx), 6);
            calculateDifference(app);
        end

        % Value changed function: DeltaSpinner
        function DeltaSpinnerValueChanged(app, event)
            value = app.DeltaSpinner.Value;
            app.hubbardModelFunctionSetup.hoppingParameterDelta = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HubbardModelFittingUIFigure = uifigure('Visible', 'off');
            app.HubbardModelFittingUIFigure.Name = 'Hubbard Model Fitting';
            app.HubbardModelFittingUIFigure.CloseRequestFcn = createCallbackFcn(app, @HubbardModelFittingUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.HubbardModelFittingUIFigure);
            app.FunctionSetupTab = uitab(app.TabGroup);
            app.FunctionSetupTab.Title = 'Function Setup';
            app.Step1HubbardModelFunctionSetupPanel = uipanel(app.FunctionSetupTab);
            app.Step1HubbardModelFunctionSetupPanel.Title = 'Step 1: Hubbard Model Function Setup';
            app.hubbardFunctionDisplay = uiaxes(app.Step1HubbardModelFunctionSetupPanel);
            title(app.hubbardFunctionDisplay, 'Hubbard Model Function')
            xlabel(app.hubbardFunctionDisplay, '')
            ylabel(app.hubbardFunctionDisplay, '')
            app.hubbardFunctionDisplay.XTick = [];
            app.hubbardFunctionDisplay.YTick = [];
            app.Image = uiimage(app.Step1HubbardModelFunctionSetupPanel);
            app.Image.ImageSource = 'equation.png';
            app.ImportHoppingMatrixButton = uibutton(app.Step1HubbardModelFunctionSetupPanel, 'push');
            app.ImportHoppingMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHoppingMatrixButtonPushed, true);
            app.ImportHoppingMatrixButton.Enable = 'off';
            app.ImportHoppingMatrixButton.Text = 'Import Hopping Matrix';
            app.hoppingData = uitable(app.Step1HubbardModelFunctionSetupPanel);
            app.hoppingData.ColumnName = {'Neighbour'; 'g1'; 'g2'; 'g3'};
            app.hoppingData.RowName = {''};
            app.hoppingData.ColumnSortable = [true false false false];
            app.hoppingData.ColumnEditable = true;
            app.hoppingData.CellEditCallback = createCallbackFcn(app, @hoppingDataCellEdit, true);
            app.NeighbourOrderSpinnerLabel = uilabel(app.Step1HubbardModelFunctionSetupPanel);
            app.NeighbourOrderSpinnerLabel.Text = 'Neighbour Order';
            app.NeighbourOrderSpinner = uispinner(app.Step1HubbardModelFunctionSetupPanel);
            app.NeighbourOrderSpinner.Limits = [1 Inf];
            app.NeighbourOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @NeighbourOrderSpinnerValueChanged, true);
            app.NeighbourOrderSpinner.Value = 2;
            app.ConfirmSetupButton = uibutton(app.Step1HubbardModelFunctionSetupPanel, 'push');
            app.ConfirmSetupButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSetupButtonPushed, true);
            app.ConfirmSetupButton.Text = 'Confirm Setup';
            app.ResetButton = uibutton(app.Step1HubbardModelFunctionSetupPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetupModeButtonGroup = uibuttongroup(app.Step1HubbardModelFunctionSetupPanel);
            app.SetupModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetupModeButtonGroupSelectionChanged, true);
            app.SetupModeButtonGroup.Title = 'Setup Mode';
            app.ImportFromFileButton_2 = uitogglebutton(app.SetupModeButtonGroup);
            app.ImportFromFileButton_2.Text = 'Import From File';
            app.ManualButton_2 = uitogglebutton(app.SetupModeButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.SaveequationtotheclipboardButton = uibutton(app.Step1HubbardModelFunctionSetupPanel, 'push');
            app.SaveequationtotheclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @SaveequationtotheclipboardButtonPushed, true);
            app.SaveequationtotheclipboardButton.Text = 'Save equation to the clipboard';
            app.Step2HoppingparameterInitializationPanel = uipanel(app.FunctionSetupTab);
            app.Step2HoppingparameterInitializationPanel.Title = 'Step 2: Hopping parameter Initialization';
            app.hoppingParameter = uitable(app.Step2HoppingparameterInitializationPanel);
            app.hoppingParameter.ColumnName = {'Neighbour'; 'Value'};
            app.hoppingParameter.RowName = {''};
            app.hoppingParameter.ColumnSortable = [true false];
            app.hoppingParameter.ColumnEditable = true;
            app.hoppingParameter.CellEditCallback = createCallbackFcn(app, @hoppingParameterCellEdit, true);
            app.hoppingParameter.Enable = 'off';
            app.ImportHoppingParameterButton = uibutton(app.Step2HoppingparameterInitializationPanel, 'push');
            app.ImportHoppingParameterButton.ButtonPushedFcn = createCallbackFcn(app, @ImportHoppingParameterButtonPushed, true);
            app.ImportHoppingParameterButton.Enable = 'off';
            app.ImportHoppingParameterButton.Text = 'Import Hopping Parameter';
            app.SetupModeButtonGroup_2 = uibuttongroup(app.Step2HoppingparameterInitializationPanel);
            app.SetupModeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SetupModeButtonGroup_2SelectionChanged, true);
            app.SetupModeButtonGroup_2.Title = 'Setup Mode';
            app.ImportFromFileButton_3 = uitogglebutton(app.SetupModeButtonGroup_2);
            app.ImportFromFileButton_3.Enable = 'off';
            app.ImportFromFileButton_3.Text = 'Import From File';
            app.ManualButton_3 = uitogglebutton(app.SetupModeButtonGroup_2);
            app.ManualButton_3.Enable = 'off';
            app.ManualButton_3.Text = 'Manual';
            app.ManualButton_3.Value = true;
            app.t1SpinnerLabel = uilabel(app.Step2HoppingparameterInitializationPanel);
            app.t1SpinnerLabel.Enable = 'off';
            app.t1SpinnerLabel.Text = 't1';
            app.t1Spinner = uispinner(app.Step2HoppingparameterInitializationPanel);
            app.t1Spinner.Step = 0.01;
            app.t1Spinner.ValueDisplayFormat = '%.4f';
            app.t1Spinner.ValueChangedFcn = createCallbackFcn(app, @t1SpinnerValueChanged, true);
            app.t1Spinner.Enable = 'off';
            app.t2Label = uilabel(app.Step2HoppingparameterInitializationPanel);
            app.t2Label.Enable = 'off';
            app.t2Label.Text = 't2';
            app.t2Spinner = uispinner(app.Step2HoppingparameterInitializationPanel);
            app.t2Spinner.Step = 0.01;
            app.t2Spinner.ValueDisplayFormat = '%.4f';
            app.t2Spinner.ValueChangedFcn = createCallbackFcn(app, @t2SpinnerValueChanged, true);
            app.t2Spinner.Enable = 'off';
            app.t3SpinnerLabel = uilabel(app.Step2HoppingparameterInitializationPanel);
            app.t3SpinnerLabel.Enable = 'off';
            app.t3SpinnerLabel.Text = 't3';
            app.t3Spinner = uispinner(app.Step2HoppingparameterInitializationPanel);
            app.t3Spinner.Step = 0.01;
            app.t3Spinner.ValueDisplayFormat = '%.4f';
            app.t3Spinner.ValueChangedFcn = createCallbackFcn(app, @t3SpinnerValueChanged, true);
            app.t3Spinner.Enable = 'off';
            app.t4SpinnerLabel = uilabel(app.Step2HoppingparameterInitializationPanel);
            app.t4SpinnerLabel.Enable = 'off';
            app.t4SpinnerLabel.Text = 't4';
            app.t4Spinner = uispinner(app.Step2HoppingparameterInitializationPanel);
            app.t4Spinner.Step = 0.01;
            app.t4Spinner.ValueDisplayFormat = '%.4f';
            app.t4Spinner.ValueChangedFcn = createCallbackFcn(app, @t4SpinnerValueChanged, true);
            app.t4Spinner.Enable = 'off';
            app.ConfirmSetupButton_2 = uibutton(app.Step2HoppingparameterInitializationPanel, 'push');
            app.ConfirmSetupButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSetupButton_2Pushed, true);
            app.ConfirmSetupButton_2.Enable = 'off';
            app.ConfirmSetupButton_2.Text = 'Confirm Setup';
            app.ResetButton_2 = uibutton(app.Step2HoppingparameterInitializationPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.DeltaSpinnerLabel = uilabel(app.Step2HoppingparameterInitializationPanel);
            app.DeltaSpinnerLabel.Enable = 'off';
            app.DeltaSpinnerLabel.Text = 'Delta';
            app.DeltaSpinner = uispinner(app.Step2HoppingparameterInitializationPanel);
            app.DeltaSpinner.ValueDisplayFormat = '%.4f';
            app.DeltaSpinner.ValueChangedFcn = createCallbackFcn(app, @DeltaSpinnerValueChanged, true);
            app.DeltaSpinner.Enable = 'off';
            app.FunctionFittingTab = uitab(app.TabGroup);
            app.FunctionFittingTab.Title = 'Function Fitting';
            app.ImportKmeshButton = uibutton(app.FunctionFittingTab, 'push');
            app.ImportKmeshButton.ButtonPushedFcn = createCallbackFcn(app, @ImportKmeshButtonPushed, true);
            app.ImportKmeshButton.Text = 'Import K-mesh';
            app.KMeshFigure = uiaxes(app.FunctionFittingTab);
            title(app.KMeshFigure, 'K-Mesh')
            xlabel(app.KMeshFigure, 'k_x')
            ylabel(app.KMeshFigure, 'k_y')
            app.CalculateHamiltonianButton = uibutton(app.FunctionFittingTab, 'push');
            app.CalculateHamiltonianButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateHamiltonianButtonPushed, true);
            app.CalculateHamiltonianButton.Text = 'Calculate Hamiltonian';
            app.DifferenceFigure = uiaxes(app.FunctionFittingTab);
            title(app.DifferenceFigure, 'Difference')
            xlabel(app.DifferenceFigure, 'k_x')
            ylabel(app.DifferenceFigure, 'k_y')
            % Show the figure after all components are created
            app.HubbardModelFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hubbardFitting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HubbardModelFittingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HubbardModelFittingUIFigure)
        end
    end
end